classdef ExportDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportData               matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        EditMenu                 matlab.ui.container.Menu
        ViewMenu                 matlab.ui.container.Menu
        DataBrowserPanel         matlab.ui.container.Panel
        DataBrowserTree          matlab.ui.container.Tree
        FolderNode               matlab.ui.container.TreeNode
        Node_3                   matlab.ui.container.TreeNode
        Node_8                   matlab.ui.container.TreeNode
        S21Node                  matlab.ui.container.TreeNode
        Node_9                   matlab.ui.container.TreeNode
        S21Node_2                matlab.ui.container.TreeNode
        Node_10                  matlab.ui.container.TreeNode
        S21Node_3                matlab.ui.container.TreeNode
        Node_6                   matlab.ui.container.TreeNode
        Node_7                   matlab.ui.container.TreeNode
        Node                     matlab.ui.container.TreeNode
        Node_4                   matlab.ui.container.TreeNode
        Node_2                   matlab.ui.container.TreeNode
        Node_5                   matlab.ui.container.TreeNode
        SelectItemsListBoxLabel  matlab.ui.control.Label
        SelectedDataListBox      matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        MainAppObj;
    end
    
    methods (Access = public)
        
        function addFolderToTree(app,folderNode,folderToAdd)
            listOfFiles = dir(folderToAdd);
            folderSize = size(listOfFiles,1);
            
            for idx = 1:folderSize
                drawnow();
                
                if (~startsWith(listOfFiles(idx).name,"."))
                    if (listOfFiles(idx).isdir)
                        newNode = uitreenode(folderNode,'Text',listOfFiles(idx).name);
                        if(isunix | ismac) addFolderToTree(app,newNode,[listOfFiles(idx).folder '/' listOfFiles(idx).name]); end
                        if(ispc) addFolderToTree(app,newNode,[listOfFiles(idx).folder '\' listOfFiles(idx).name]); end
                    else
                        uitreenode(folderNode,'Text',listOfFiles(idx).name);
                    end
                end
            end
            
        end
        
        function updateFileTree(app,rootFolder)
            delete(app.FolderNode.Children)
            app.FolderNode.Text = rootFolder;
            
            f = uifigure('Name','Directory Indexing');
            fileLoadingProgressBar = uiprogressdlg(f,'Title','Opening Folder and Subfolders. . .','Indeterminate','on');
            pause(.5)
            app.MainAppObj.setAppOnTop(true,f.Name);
            addFolderToTree(app,app.FolderNode,rootFolder);
            
            close(fileLoadingProgressBar);
            delete(fileLoadingProgressBar);
            clear fileLoadingProgressBar;
            close(f)
            delete(f)
            clear f;
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp)
            
            app.CallingAppObj = callingApp;
            app.MainAppObj = mainApp;
            %updateFileTree(app,app.MainAppObj.ProjectDirectoryNameObj);
            pause(0.25);
            
            app.MainAppObj.setAppOnTop(true,app.ExportData.Name);
            
            
        end

        % Close request function: ExportData
        function ExportDataCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: DataBrowserTree
        function DataBrowserTreeSelectionChanged(app, event)
%%%EXPERIMENTAL
            
            %             selectedNodes = app.DataBrowserTree.SelectedNodes;
%             
%             for i = 1:length(app.MainAppObj.DataToWriteObj)
%                 if (app.MainAppObj.DataToWriteObj{i}(1,2,1) == str2num(selectedNodes.Text))
%                     for j = 1:length(app.MainAppObj.DataToWriteObj{2}(1,1,:))
%                         app.SelectedDataListBox.Items = {app.SelectedDataListBox.Items{1:end} num2str(app.MainAppObj.DataToWriteObj{2}(1,3,j))};
%                         if (mod(i,15) == 0) drawnow(); end
%                     end
%                     break;
%                 end
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportData = uifigure('Visible', 'off');
            app.ExportData.Name = 'Select Data to Export';
            app.ExportData.CloseRequestFcn = createCallbackFcn(app, @ExportDataCloseRequest, true);
            app.FileMenu = uimenu(app.ExportData);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.ExportData);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.ExportData);
            app.ViewMenu.Text = 'View';
            app.DataBrowserPanel = uipanel(app.ExportData);
            app.DataBrowserPanel.Title = 'Data Browser';
            app.DataBrowserTree = uitree(app.DataBrowserPanel);
            app.DataBrowserTree.Multiselect = 'on';
            app.DataBrowserTree.SelectionChangedFcn = createCallbackFcn(app, @DataBrowserTreeSelectionChanged, true);
            app.FolderNode = uitreenode(app.DataBrowserTree);
            app.FolderNode.Text = '-90';
            app.Node_3 = uitreenode(app.FolderNode);
            app.Node_3.Text = '0';
            app.Node_8 = uitreenode(app.Node_3);
            app.Node_8.Text = '1.0';
            app.S21Node = uitreenode(app.Node_8);
            app.S21Node.Text = 'S21';
            app.Node_9 = uitreenode(app.Node_3);
            app.Node_9.Text = '1.1';
            app.S21Node_2 = uitreenode(app.Node_9);
            app.S21Node_2.Text = 'S21';
            app.Node_10 = uitreenode(app.Node_3);
            app.Node_10.Text = '1.2';
            app.S21Node_3 = uitreenode(app.Node_10);
            app.S21Node_3.Text = 'S21';
            app.Node_6 = uitreenode(app.FolderNode);
            app.Node_6.Text = '1';
            app.Node_7 = uitreenode(app.FolderNode);
            app.Node_7.Text = '2';
            app.Node = uitreenode(app.DataBrowserTree);
            app.Node.Text = '-89';
            app.Node_4 = uitreenode(app.Node);
            app.Node_4.Text = '0';
            app.Node_2 = uitreenode(app.DataBrowserTree);
            app.Node_2.Text = '-88';
            app.Node_5 = uitreenode(app.Node_2);
            app.Node_5.Text = '0';
            app.SelectItemsListBoxLabel = uilabel(app.ExportData);
            app.SelectItemsListBoxLabel.Text = 'Select Items';
            app.SelectedDataListBox = uilistbox(app.ExportData);
            app.SelectedDataListBox.Items = {};
            app.SelectedDataListBox.Multiselect = 'on';
            app.SelectedDataListBox.Value = {};
            % Show the figure after all components are created
            app.ExportData.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportDataApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportData)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportData)
        end
    end
end