classdef NewMeasurementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewMeasApp                     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        SetParamsPanel                 matlab.ui.container.Panel
        VNAPanel                       matlab.ui.container.Panel
        SpanFrequencyCheckBox          matlab.ui.control.CheckBox
        FreqUnitsDropDown              matlab.ui.control.DropDown
        ConfigureVNAButton             matlab.ui.control.Button
        StartFreqUnitsDropDown         matlab.ui.control.DropDown
        StopFreqUnitsDropDown          matlab.ui.control.DropDown
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        StopEditFieldLabel             matlab.ui.control.Label
        StopEditField                  matlab.ui.control.NumericEditField
        NextButton                     matlab.ui.control.Button
        TestRunCheckBox                matlab.ui.control.CheckBox
        PositionControllerPanel        matlab.ui.container.Panel
        AzimuthParametersPanel         matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        AZLimitsLabel                  matlab.ui.control.Label
        IncrementSizeEditFieldLabel    matlab.ui.control.Label
        AZIncrementSizeEditField       matlab.ui.control.NumericEditField
        StartPositionEditFieldLabel    matlab.ui.control.Label
        AZStartPositionEditField       matlab.ui.control.NumericEditField
        EndPositionEditFieldLabel      matlab.ui.control.Label
        AZEndPositionEditField         matlab.ui.control.NumericEditField
        RollParametersPanel            matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        RollLimitsLabel                matlab.ui.control.Label
        RollIncrementSizeEditFieldLabel  matlab.ui.control.Label
        RollIncrementSizeEditField     matlab.ui.control.NumericEditField
        RollStartPositionEditFieldLabel  matlab.ui.control.Label
        RollStartPositionEditField     matlab.ui.control.NumericEditField
        RollEndPositionEditFieldLabel  matlab.ui.control.Label
        RollEndPositionEditField       matlab.ui.control.NumericEditField
        FixedPositionCheckBox          matlab.ui.control.CheckBox
        ConfigurePositionControllerButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        TakeMeasurementAppObj;
        
        ProjectDirectoryObj;
        ProjectFileObj;
    
    end
    
    methods (Access = private)
        
        function openTakeMeasurementApp(app)
            AZIncrementSize = app.AZIncrementSizeEditField.Value;
            AZStartPos = app.AZStartPositionEditField.Value;
            AZEndPos = app.AZEndPositionEditField.Value;
            
            RollIncrementSize = app.RollIncrementSizeEditField.Value;
            RollStartPos = app.RollStartPositionEditField.Value;
            RollEndPos = app.RollEndPositionEditField.Value;
            
            freqValue = app.FrequencyEditField.Value;
            freqUnits = app.FreqUnitsDropDown.Value;
            startFreqUnits = app.StartFreqUnitsDropDown.Value;
            stopFreqUnits = app.StopFreqUnitsDropDown.Value;
            startFreq = app.StartEditField.Value;
            stopFreq = app.StopEditField.Value;
            spanFreq = app.SpanFrequencyCheckBox.Value;    
            
            fixedRollPosition = app.FixedPositionCheckBox.Value;
            
            app.TakeMeasurementAppObj = TakeMeasurementApp(app,app.CallingAppObj,AZIncrementSize,AZStartPos,AZEndPos, RollIncrementSize,RollStartPos,RollEndPos, ...
                freqValue,freqUnits, startFreqUnits, stopFreqUnits,startFreq,stopFreq,spanFreq, app.TestRunCheckBox.Value, fixedRollPosition);
            pause(0.1);
            app.CallingAppObj.TakeMeasurementAppObj = app.TakeMeasurementAppObj;
            app.NewMeasAppCloseRequest(app);
        end
    end
    
    methods (Access = public)
        
        function checkFields(app)
            
            if (app.SpanFrequencyCheckBox.Value)
                if (app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                    if (app.AZIncrementSizeEditField.Value > 0 && app.AZIncrementSizeEditField.Value <= 180)
                        if (app.FixedPositionCheckBox.Value)
                            app.NextButton.Enable = 'on';
                        elseif (app.RollIncrementSizeEditField.Value > 0 && app.RollIncrementSizeEditField.Value <= 360)
                            app.NextButton.Enable = 'on';
                        else
                            app.NextButton.Enable = 'off';
                        end
                    else
                            app.NextButton.Enable = 'off';
                    end
                else
                            app.NextButton.Enable = 'off';
                end
            elseif (app.FrequencyEditField.Value > 0)
                    if (app.AZIncrementSizeEditField.Value > 0 && app.AZIncrementSizeEditField.Value <= 180)
                        if (app.FixedPositionCheckBox.Value)
                            app.NextButton.Enable = 'on';
                        elseif (app.RollIncrementSizeEditField.Value > 0 && app.RollIncrementSizeEditField.Value <= 360)
                            app.NextButton.Enable = 'on';
                        else
                            app.NextButton.Enable = 'off';
                        end
                    else
                            app.NextButton.Enable = 'off';
                    end
            else
                            app.NextButton.Enable = 'off';
            end
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.CallingAppObj = callingApp;
            pause(0.5);
            app.CallingAppObj.setAppOnTop(true,app.NewMeasApp.Name);
            app.ProjectDirectoryObj = app.CallingAppObj.ProjectDirectoryObj;
            app.ProjectFileObj = app.CallingAppObj.ProjectFileObj;
        end

        % Value changed function: SpanFrequencyCheckBox
        function SpanFrequencyCheckBoxValueChanged(app, event)
            spanFreqCheckBoxValue = app.SpanFrequencyCheckBox.Value;
            if (spanFreqCheckBoxValue)
                app.StartEditField.Enable = 'on';
                app.StopEditField.Enable = 'on';
                app.StartEditFieldLabel.Enable = 'on';
                app.StopEditFieldLabel.Enable = 'on';
                app.StartFreqUnitsDropDown.Enable = 'on';
                app.StopFreqUnitsDropDown.Enable = 'on';
                app.FrequencyEditField.Enable = 'off';
                app.FrequencyEditFieldLabel.Enable = 'off';
                app.FreqUnitsDropDown.Enable = 'off';
                
                if (app.AZIncrementSizeEditField.Value <= 0 || app.AZIncrementSizeEditField.Value > 180)
                    app.AZIncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (~app.FixedPositionCheckBox.Value && (app.RollIncrementSizeEditField.Value <= 0 || app.RollIncrementSizeEditField > 360))
                    app.RollIncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (app.FixedPositionCheckBox.Value)
                    app.NextButton.Enable = 'on';
                elseif (app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                else
                    app.NextButton.Enable = 'off';
                end
                
            else
                app.StartEditField.Enable = 'off';
                app.StopEditField.Enable = 'off';
                app.StartEditFieldLabel.Enable = 'off';
                app.StopEditFieldLabel.Enable = 'off';
                app.StartFreqUnitsDropDown.Enable = 'off';
                app.StopFreqUnitsDropDown.Enable = 'off';
                app.FrequencyEditField.Enable = 'on';
                app.FrequencyEditFieldLabel.Enable = 'on';
                app.FreqUnitsDropDown.Enable = 'on';
                
                if (app.AZIncrementSizeEditField.Value <= 0 || app.AZIncrementSizeEditField.Value > 180)
                    app.AZIncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (~app.FixedPositionCheckBox.Value && (app.RollIncrementSizeEditField.Value <= 0 || app.RollIncrementSizeEditField > 360))
                    app.RollIncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (app.FixedPositionCheckBox.Value)
                    app.NextButton.Enable = 'on';
                elseif (app.FrequencyEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                else
                    app.NextButton.Enable = 'off';
                end
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if (app.AZIncrementSizeEditField.Value < 0 || app.AZIncrementSizeEditField.Value > 180)
                fprintf("WARNING: AZ INCREMENT SIZE OUTSIDE OF ALLOWABLE RANGE. PLEASE FIX AND TRY AGAIN!\n");
                return;
            elseif (app.RollIncrementSizeEditField.Value < 0 || app.RollIncrementSizeEditField.Value > 360)
                fprintf("WARNING: Roll INCREMENT SIZE OUTSIDE OF ALLOWABLE RANGE. PLEASE FIX AND TRY AGAIN!\n");
                return;
            elseif (app.SpanFrequencyCheckBox.Value)
                if (app.StartEditField.Value < 0 || app.StopEditField.Value < 0)
                    fprintf("WARNING: SPAN FREQUENCY RANGE SIZE OUTSIDE OF ALLOWABLE LIMITS. PLEASE FIX AND TRY AGAIN!\n");
                    return;
                else
                    openTakeMeasurementApp(app);
                end
            elseif (app.FrequencyEditField.Value < 0)
                fprintf("WARNING: FREQUENCY VALUE OUTSIDE OF ALLOWABLE RANGE. PLEASE FIX AND TRY AGAIN!\n");
                return;
            else
                openTakeMeasurementApp(app);
            end
            
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            freqInputValue = app.FrequencyEditField.Value;
            if (freqInputValue < 0)
                app.FrequencyEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (app.AZIncrementSizeEditField.Value > 0 && app.AZIncrementSizeEditField.Value <= 180 && ...
                    ~app.FixedPositionCheckBox.Value && app.RollIncrementSizeEditField.Value > 0 && app.RollIncrementSizeEditField.Value <= 360 && freqInputValue ~= 0)
                app.NextButton.Enable = 'on';
            elseif (app.AZIncrementSizeEditField.Value > 0 && app.AZIncrementSizeEditField.Value <= 180 && ...
                    app.FixedPositionCheckBox.Value && freqInputValue ~= 0)
                app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Value changed function: AZIncrementSizeEditField
        function AZIncrementSizeEditFieldValueChanged(app, event)
            AZIncrementSizeInputValue = app.AZIncrementSizeEditField.Value;
            
            if (AZIncrementSizeInputValue <= 0 || AZIncrementSizeInputValue > 180)
                app.AZIncrementSizeEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (~app.FixedPositionCheckBox.Value && (app.RollIncrementSizeEditField.Value <= 0 || app.RollIncrementSizeEditField.Value > 360))
                app.RollIncrementSizeEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (app.FixedPositionCheckBox.Value)
                    app.NextButton.Enable = 'on';
            elseif (app.SpanFrequencyCheckBox.Value == 0 && app.FrequencyEditField.Value > 0)
                app.NextButton.Enable = 'on';
            elseif (app.SpanFrequencyCheckBox.Value ~= 0 && app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Close request function: NewMeasApp
        function NewMeasAppCloseRequest(app, event)
            app.CallingAppObj.NewMeasurementSetupButton.Enable = 'on';
            delete(app)
            
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            spanFreqInputValue = app.StartEditField.Value;
            
            if (spanFreqInputValue <= 0)
                app.StartEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (spanFreqInputValue > 0 && app.StopEditField.Value > 0 && app.AZIncrementSizeEditField.Value > 0 && app.AZIncrementSizeEditField.Value <= 180 && ...
                    ~app.FixedPositionCheckBox.Value && app.RollIncrementSizeEditField.Value > 0 && app.RollIncrementSizeEditField.Value <= 360)
                app.NextButton.Enable = 'on';
            elseif (app.FixedPositionCheckBox.Value)
                    app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Value changed function: TestRunCheckBox
        function TestRunCheckBoxValueChanged(app, event)
            value = app.TestRunCheckBox.Value;
            
        end

        % Value changed function: RollIncrementSizeEditField
        function RollIncrementSizeEditFieldValueChanged(app, event)
            RollIncrementSizeInputValue = app.RollIncrementSizeEditField.Value;
            
            if (RollIncrementSizeInputValue <= 0 || RollIncrementSizeInputValue > 360)
                app.RollIncrementSizeEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (app.AZIncrementSizeEditField.Value <= 0 || app.AZIncrementSizeEditField.Value > 180)
                app.AZIncrementSizeEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (app.SpanFrequencyCheckBox.Value == 0 && app.FrequencyEditField.Value > 0)
                app.NextButton.Enable = 'on';
            elseif (app.SpanFrequencyCheckBox.Value ~= 0 && app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Value changed function: StopEditField
        function StopEditFieldValueChanged(app, event)
            spanFreqInputValue = app.StopEditField.Value;
            
            if (spanFreqInputValue <= 0)
                app.StopEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (spanFreqInputValue > 0 && app.StartEditField.Value > 0 && app.AZIncrementSizeEditField.Value > 0 && app.AZIncrementSizeEditField.Value <= 180 && ...
                    ~app.FixedPositionCheckBox.Value && app.RollIncrementSizeEditField.Value > 0 && app.RollIncrementSizeEditField.Value <= 360)
                app.NextButton.Enable = 'on';
            elseif (app.FixedPositionCheckBox.Value)
                    app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Value changed function: FixedPositionCheckBox
        function FixedPositionCheckBoxValueChanged(app, event)
            fixedPositionValue = app.FixedPositionCheckBox.Value;
            
            if (fixedPositionValue) 
                app.RollIncrementSizeEditField.Enable = 'off';
                app.RollStartPositionEditField.Enable = 'off';
                app.RollEndPositionEditField.Enable = 'off';
                app.RollIncrementSizeEditFieldLabel.Enable = 'off';
                app.RollStartPositionEditFieldLabel.Enable = 'off';
                app.RollEndPositionEditFieldLabel.Enable = 'off';
                app.RollIncrementSizeEditField.Value = 0;
                if (app.AZIncrementSizeEditField.Value <= 0 || app.AZIncrementSizeEditField.Value > 180)
                    app.AZIncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (app.SpanFrequencyCheckBox.Value == 0 && app.FrequencyEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                elseif (app.SpanFrequencyCheckBox.Value ~= 0 && app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                else
                    app.NextButton.Enable = 'off';
                end
            else
                app.RollIncrementSizeEditField.Enable = 'on';
                app.RollStartPositionEditField.Enable = 'on';
                app.RollEndPositionEditField.Enable = 'on';
                app.RollIncrementSizeEditFieldLabel.Enable = 'on';
                app.RollStartPositionEditFieldLabel.Enable = 'on';
                app.RollEndPositionEditFieldLabel.Enable = 'on';
                
                if (app.RollIncrementSizeEditField.Value <= 0 || app.RollIncrementSizeEditField.Value > 360)
                    app.RollIncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (app.AZIncrementSizeEditField.Value <= 0 || app.AZIncrementSizeEditField.Value > 180)
                    app.AZIncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (app.SpanFrequencyCheckBox.Value == 0 && app.FrequencyEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                elseif (app.SpanFrequencyCheckBox.Value ~= 0 && app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                else
                    app.NextButton.Enable = 'off';
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewMeasApp = uifigure('Visible', 'off');
            app.NewMeasApp.Name = 'New Measurement / Setup';
            app.NewMeasApp.CloseRequestFcn = createCallbackFcn(app, @NewMeasAppCloseRequest, true);
            app.FileMenu = uimenu(app.NewMeasApp);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.EditMenu = uimenu(app.NewMeasApp);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.NewMeasApp);
            app.ViewMenu.Text = 'View';
            app.SetParamsPanel = uipanel(app.NewMeasApp);
            app.SetParamsPanel.Title = 'Set Parameters For Measurement';
            app.VNAPanel = uipanel(app.SetParamsPanel);
            app.VNAPanel.Title = 'VNA';
            app.SpanFrequencyCheckBox = uicheckbox(app.VNAPanel);
            app.SpanFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpanFrequencyCheckBoxValueChanged, true);
            app.SpanFrequencyCheckBox.Text = ' Span Frequency';
            app.FreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.FreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.FreqUnitsDropDown.Value = 'GHz';
            app.ConfigureVNAButton = uibutton(app.VNAPanel, 'push');
            app.ConfigureVNAButton.Text = 'Configure Instrument';
            app.StartFreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.StartFreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StartFreqUnitsDropDown.Enable = 'off';
            app.StartFreqUnitsDropDown.Value = 'GHz';
            app.StopFreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.StopFreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StopFreqUnitsDropDown.Enable = 'off';
            app.StopFreqUnitsDropDown.Value = 'GHz';
            app.FrequencyEditFieldLabel = uilabel(app.VNAPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.VNAPanel, 'numeric');
            app.FrequencyEditField.ValueDisplayFormat = '%11.6f';
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.VNAPanel);
            app.StartEditFieldLabel.Enable = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StartEditField.ValueDisplayFormat = '%11.6f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditField.Enable = 'off';
            app.StopEditFieldLabel = uilabel(app.VNAPanel);
            app.StopEditFieldLabel.Enable = 'off';
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StopEditField.ValueDisplayFormat = '%11.6f';
            app.StopEditField.ValueChangedFcn = createCallbackFcn(app, @StopEditFieldValueChanged, true);
            app.StopEditField.Enable = 'off';
            app.NextButton = uibutton(app.SetParamsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.TestRunCheckBox = uicheckbox(app.SetParamsPanel);
            app.TestRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestRunCheckBoxValueChanged, true);
            app.TestRunCheckBox.Text = 'Test Run';
            app.PositionControllerPanel = uipanel(app.SetParamsPanel);
            app.PositionControllerPanel.Title = 'Position Controller';
            app.AzimuthParametersPanel = uipanel(app.PositionControllerPanel);
            app.AzimuthParametersPanel.Title = 'Azimuth Parameters';
            app.GridLayout = uigridlayout(app.AzimuthParametersPanel);
            app.AZLimitsLabel = uilabel(app.GridLayout);
            app.AZLimitsLabel.Text = '(1-180)';
            app.IncrementSizeEditFieldLabel = uilabel(app.GridLayout);
            app.IncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.AZIncrementSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.AZIncrementSizeEditField.ValueChangedFcn = createCallbackFcn(app, @AZIncrementSizeEditFieldValueChanged, true);
            app.StartPositionEditFieldLabel = uilabel(app.GridLayout);
            app.StartPositionEditFieldLabel.Text = 'Start Position';
            app.AZStartPositionEditField = uieditfield(app.GridLayout, 'numeric');
            app.AZStartPositionEditField.Value = -90;
            app.EndPositionEditFieldLabel = uilabel(app.GridLayout);
            app.EndPositionEditFieldLabel.Text = 'End Position';
            app.AZEndPositionEditField = uieditfield(app.GridLayout, 'numeric');
            app.AZEndPositionEditField.Value = 90;
            app.RollParametersPanel = uipanel(app.PositionControllerPanel);
            app.RollParametersPanel.Title = 'Roll Parameters';
            app.GridLayout2 = uigridlayout(app.RollParametersPanel);
            app.RollLimitsLabel = uilabel(app.GridLayout2);
            app.RollLimitsLabel.Text = '(1-360)';
            app.RollIncrementSizeEditFieldLabel = uilabel(app.GridLayout2);
            app.RollIncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.RollIncrementSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollIncrementSizeEditField.ValueChangedFcn = createCallbackFcn(app, @RollIncrementSizeEditFieldValueChanged, true);
            app.RollStartPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.RollStartPositionEditFieldLabel.Text = 'Start Position';
            app.RollStartPositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollEndPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.RollEndPositionEditFieldLabel.Text = 'End Position';
            app.RollEndPositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollEndPositionEditField.Value = 360;
            app.ConfigurePositionControllerButton = uibutton(app.PositionControllerPanel, 'push');
            app.ConfigurePositionControllerButton.Text = 'Configure Instrument';
            app.FixedPositionCheckBox = uicheckbox(app.PositionControllerPanel);
            app.FixedPositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedPositionCheckBoxValueChanged, true);
            app.FixedPositionCheckBox.Text = 'Fixed Position';
            % Show the figure after all components are created
            app.NewMeasApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewMeasurementApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewMeasApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewMeasApp)
        end
    end
end