classdef NewMeasurementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewMeasApp                     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        SetParamsPanel                 matlab.ui.container.Panel
        VNAPanel                       matlab.ui.container.Panel
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        StopEditFieldLabel             matlab.ui.control.Label
        StopEditField                  matlab.ui.control.NumericEditField
        SpanFrequencyCheckBox          matlab.ui.control.CheckBox
        FreqUnitsDropDown              matlab.ui.control.DropDown
        ConfigureVNAButton             matlab.ui.control.Button
        StartFreqUnitsDropDown         matlab.ui.control.DropDown
        StopFreqUnitsDropDown          matlab.ui.control.DropDown
        PositionControllerPanel        matlab.ui.container.Panel
        IncrementSizeEditFieldLabel    matlab.ui.control.Label
        IncrementSizeEditField         matlab.ui.control.NumericEditField
        StartPositionEditFieldLabel    matlab.ui.control.Label
        StartPositionEditField         matlab.ui.control.NumericEditField
        EndPositionEditFieldLabel      matlab.ui.control.Label
        EndPositionEditField           matlab.ui.control.NumericEditField
        PositionControllerLimitsLabel  matlab.ui.control.Label
        ConfigurePositionControllerButton  matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        TestRunCheckBox                matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        TakeMeasurementAppObj;
        
        ProjectDirectoryObj;
        ProjectFileObj;
    
    end
    
    methods (Access = private)
        
        function openTakeMeasurementApp(app)
            incrementSize = app.IncrementSizeEditField.Value;
            startPos = app.StartPositionEditField.Value;
            endPos = app.EndPositionEditField.Value;
            
            freqValue = app.FrequencyEditField.Value;
            freqUnits = app.FreqUnitsDropDown.Value;
            startFreqUnits = app.StartFreqUnitsDropDown.Value;
            stopFreqUnits = app.StopFreqUnitsDropDown.Value;
            startFreq = app.StartEditField.Value;
            stopFreq = app.StopEditField.Value;
            spanFreq = app.SpanFrequencyCheckBox.Value;            
            
            app.TakeMeasurementAppObj = TakeMeasurementApp(app,app.CallingAppObj,incrementSize,startPos,endPos,freqValue,freqUnits, ...
                startFreqUnits, stopFreqUnits,startFreq,stopFreq,spanFreq, app.TestRunCheckBox.Value);
            pause(0.1);
            app.CallingAppObj.TakeMeasurementAppObj = app.TakeMeasurementAppObj;
            app.NewMeasAppCloseRequest(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            app.CallingAppObj = callingApp;
            pause(0.5);
            app.CallingAppObj.setAppOnTop(true,app.NewMeasApp.Name);
            app.ProjectDirectoryObj = app.CallingAppObj.ProjectDirectoryObj;
            app.ProjectFileObj = app.CallingAppObj.ProjectFileObj;
        end

        % Value changed function: SpanFrequencyCheckBox
        function SpanFrequencyCheckBoxValueChanged(app, event)
            spanFreqCheckBoxValue = app.SpanFrequencyCheckBox.Value;
            if (spanFreqCheckBoxValue)
                app.StartEditField.Enable = 'on';
                app.StopEditField.Enable = 'on';
                app.StartEditFieldLabel.Enable = 'on';
                app.StopEditFieldLabel.Enable = 'on';
                app.StartFreqUnitsDropDown.Enable = 'on';
                app.StopFreqUnitsDropDown.Enable = 'on';
                app.FrequencyEditField.Enable = 'off';
                app.FrequencyEditFieldLabel.Enable = 'off';
                app.FreqUnitsDropDown.Enable = 'off';
                
                if (app.IncrementSizeEditField.Value <= 0 || app.IncrementSizeEditField.Value > 180)
                    app.IncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                else
                    app.NextButton.Enable = 'off';
                end
                
            else
                app.StartEditField.Enable = 'off';
                app.StopEditField.Enable = 'off';
                app.StartEditFieldLabel.Enable = 'off';
                app.StopEditFieldLabel.Enable = 'off';
                app.StartFreqUnitsDropDown.Enable = 'off';
                app.StopFreqUnitsDropDown.Enable = 'off';
                app.FrequencyEditField.Enable = 'on';
                app.FrequencyEditFieldLabel.Enable = 'on';
                app.FreqUnitsDropDown.Enable = 'on';
                
                if (app.IncrementSizeEditField.Value <= 0 || app.IncrementSizeEditField.Value > 180)
                    app.IncrementSizeEditField.Value = 0;
                    app.NextButton.Enable = 'off';
                elseif (app.FrequencyEditField.Value > 0)
                    app.NextButton.Enable = 'on';
                else
                    app.NextButton.Enable = 'off';
                end
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if (app.IncrementSizeEditField.Value < 0 || app.IncrementSizeEditField.Value > 180)
                fprintf("WARNING: INCREMENT SIZE OUTSIDE OF ALLOWABLE RANGE. PLEASE FIX AND TRY AGAIN!\n");
                return;
            elseif (app.SpanFrequencyCheckBox.Value)
                if (app.StartEditField.Value < 0 || app.StopEditField.Value < 0)
                    fprintf("WARNING: SPAN FREQUENCY RANGE SIZE OUTSIDE OF ALLOWABLE LIMITS. PLEASE FIX AND TRY AGAIN!\n");
                    return;
                else
                    openTakeMeasurementApp(app);
                end
            elseif (app.FrequencyEditField.Value < 0)
                fprintf("WARNING: FREQUENCY VALUE OUTSIDE OF ALLOWABLE RANGE. PLEASE FIX AND TRY AGAIN!\n");
                return;
            else
                openTakeMeasurementApp(app);
            end
            
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            freqInputValue = app.FrequencyEditField.Value;
            if (freqInputValue < 0)
                app.FrequencyEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (app.IncrementSizeEditField.Value > 0 && app.IncrementSizeEditField.Value <= 180 && freqInputValue ~= 0)
                app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Value changed function: IncrementSizeEditField
        function IncrementSizeEditFieldValueChanged(app, event)
            incrementSizeInputValue = app.IncrementSizeEditField.Value;
            
            if (incrementSizeInputValue <= 0 || incrementSizeInputValue > 180)
                app.IncrementSizeEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (app.SpanFrequencyCheckBox.Value == 0 && app.FrequencyEditField.Value > 0)
                app.NextButton.Enable = 'on';
            elseif (app.SpanFrequencyCheckBox.Value ~= 0 && app.StartEditField.Value > 0 && app.StopEditField.Value > 0)
                app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Close request function: NewMeasApp
        function NewMeasAppCloseRequest(app, event)
            app.CallingAppObj.NewMeasurementSetupButton.Enable = 'on';
            delete(app)
            
        end

        % Value changed function: StartEditField, StopEditField
        function StartEditFieldValueChanged(app, event)
            spanFreqInputValue = app.StartEditField.Value;
            
            if (spanFreqInputValue <= 0)
                app.StartEditField.Value = 0;
                app.NextButton.Enable = 'off';
            elseif (spanFreqInputValue > 0 && app.StopEditField.Value > 0 && app.IncrementSizeEditField.Value > 0 && app.IncrementSizeEditField.Value <= 180)
                app.NextButton.Enable = 'on';
            else
                app.NextButton.Enable = 'off';
            end
        end

        % Value changed function: TestRunCheckBox
        function TestRunCheckBoxValueChanged(app, event)
            value = app.TestRunCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewMeasApp = uifigure('Visible', 'off');
            app.NewMeasApp.Name = 'New Measurement / Setup';
            app.NewMeasApp.CloseRequestFcn = createCallbackFcn(app, @NewMeasAppCloseRequest, true);
            app.FileMenu = uimenu(app.NewMeasApp);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.EditMenu = uimenu(app.NewMeasApp);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.NewMeasApp);
            app.ViewMenu.Text = 'View';
            app.SetParamsPanel = uipanel(app.NewMeasApp);
            app.SetParamsPanel.Title = 'Set Parameters For Measurement';
            app.VNAPanel = uipanel(app.SetParamsPanel);
            app.VNAPanel.Title = 'VNA';
            app.FrequencyEditFieldLabel = uilabel(app.VNAPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.VNAPanel, 'numeric');
            app.FrequencyEditField.ValueDisplayFormat = '%11.6f';
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.VNAPanel);
            app.StartEditFieldLabel.Enable = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StartEditField.ValueDisplayFormat = '%11.6f';
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StartEditField.Enable = 'off';
            app.StopEditFieldLabel = uilabel(app.VNAPanel);
            app.StopEditFieldLabel.Enable = 'off';
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StopEditField.ValueDisplayFormat = '%11.6f';
            app.StopEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StopEditField.Enable = 'off';
            app.SpanFrequencyCheckBox = uicheckbox(app.VNAPanel);
            app.SpanFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpanFrequencyCheckBoxValueChanged, true);
            app.SpanFrequencyCheckBox.Text = ' Span Frequency';
            app.FreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.FreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.FreqUnitsDropDown.Value = 'GHz';
            app.ConfigureVNAButton = uibutton(app.VNAPanel, 'push');
            app.ConfigureVNAButton.Text = 'Configure Instrument';
            app.StartFreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.StartFreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StartFreqUnitsDropDown.Enable = 'off';
            app.StartFreqUnitsDropDown.Value = 'GHz';
            app.StopFreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.StopFreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StopFreqUnitsDropDown.Enable = 'off';
            app.StopFreqUnitsDropDown.Value = 'GHz';
            app.PositionControllerPanel = uipanel(app.SetParamsPanel);
            app.PositionControllerPanel.Title = 'Position Controller';
            app.IncrementSizeEditFieldLabel = uilabel(app.PositionControllerPanel);
            app.IncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.IncrementSizeEditField = uieditfield(app.PositionControllerPanel, 'numeric');
            app.IncrementSizeEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementSizeEditFieldValueChanged, true);
            app.StartPositionEditFieldLabel = uilabel(app.PositionControllerPanel);
            app.StartPositionEditFieldLabel.Text = 'Start Position';
            app.StartPositionEditField = uieditfield(app.PositionControllerPanel, 'numeric');
            app.StartPositionEditField.Value = -90;
            app.EndPositionEditFieldLabel = uilabel(app.PositionControllerPanel);
            app.EndPositionEditFieldLabel.Text = 'End Position';
            app.EndPositionEditField = uieditfield(app.PositionControllerPanel, 'numeric');
            app.EndPositionEditField.Value = 90;
            app.PositionControllerLimitsLabel = uilabel(app.PositionControllerPanel);
            app.PositionControllerLimitsLabel.Text = '(1-180)';
            app.ConfigurePositionControllerButton = uibutton(app.PositionControllerPanel, 'push');
            app.ConfigurePositionControllerButton.Text = 'Configure Instrument';
            app.NextButton = uibutton(app.SetParamsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.TestRunCheckBox = uicheckbox(app.SetParamsPanel);
            app.TestRunCheckBox.ValueChangedFcn = createCallbackFcn(app, @TestRunCheckBoxValueChanged, true);
            app.TestRunCheckBox.Text = 'Test Run';
            % Show the figure after all components are created
            app.NewMeasApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewMeasurementApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewMeasApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewMeasApp)
        end
    end
end