classdef NewProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewProjectManagerApp            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        FilePropertiesPanel             matlab.ui.container.Panel
        FileExtensionDropDown           matlab.ui.control.DropDown
        ProjectNameEditFieldLabel       matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        ProjectDirectoryEditFieldLabel  matlab.ui.control.Label
        ProjectDirectoryEditField       matlab.ui.control.EditField
        BrowseForDirectoryButton        matlab.ui.control.Button
        CreateProjectButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        MainAppObj;
        CallingAppObj; % Description
    end
    
    methods (Access = public)
        
        function createProjectButtonControl(app)
            if (~isempty(app.ProjectDirectoryEditField.Value) && ~isempty(app.ProjectNameEditField.Value))
                app.CreateProjectButton.Enable = 'on';
            else
                app.CreateProjectButton.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp)
            app.CallingAppObj = callingApp;
            app.MainAppObj = mainApp;
            app.CreateProjectButton.Enable = 'off';
            pause(0.5);
            app.MainAppObj.setAppOnTop(true,app.NewProjectManagerApp.Name);
        end

        % Close request function: NewProjectManagerApp
        function NewProjectManagerAppCloseRequest(app, event)
            % Enable the New Project button in Project Management app
            app.CallingAppObj.NewProjectButton.Enable = 'on';
            
            delete(app)            
        end

        % Button pushed function: BrowseForDirectoryButton
        function BrowseForDirectoryButtonPushed(app, event)
            app.MainAppObj.setAppOnTop(false,app.MainAppObj.MeasurementApp.Name);
            app.MainAppObj.setAppOnTop(false,app.CallingAppObj.ProjectManagementApp.Name);
            app.MainAppObj.setAppOnTop(false,app.NewProjectManagerApp.Name);
            
            selectedFolder = uigetdir;
            
            if isequal(selectedFolder,0)
               disp('User selected Cancel');
            else
               app.ProjectDirectoryEditField.Value = selectedFolder;
               app.ProjectDirectoryEditFieldValueChanged(app);
               disp(['User selected ', selectedFolder]);
            end
            
            app.MainAppObj.setAppOnTop(true,app.MainAppObj.MeasurementApp.Name);
            app.MainAppObj.setAppOnTop(true,app.CallingAppObj.ProjectManagementApp.Name);
            app.MainAppObj.setAppOnTop(true,app.NewProjectManagerApp.Name);
        end

        % Button pushed function: CreateProjectButton
        function CreateProjectButtonPushed(app, event)
            
        end

        % Value changed function: ProjectNameEditField
        function ProjectNameEditFieldValueChanged(app, event)
            value = app.ProjectNameEditField.Value;

            createProjectButtonControl(app);
        end

        % Value changed function: ProjectDirectoryEditField
        function ProjectDirectoryEditFieldValueChanged(app, event)
            value = app.ProjectDirectoryEditField.Value;
            
            createProjectButtonControl(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewProjectManagerApp = uifigure('Visible', 'off');
            app.NewProjectManagerApp.Name = 'New Project Manager';
            app.NewProjectManagerApp.CloseRequestFcn = createCallbackFcn(app, @NewProjectManagerAppCloseRequest, true);
            app.GridLayout = uigridlayout(app.NewProjectManagerApp);
            app.FilePropertiesPanel = uipanel(app.GridLayout);
            app.FilePropertiesPanel.Title = 'File Properties';
            app.FileExtensionDropDown = uidropdown(app.FilePropertiesPanel);
            app.FileExtensionDropDown.Items = {'.mdb', '.txt', '.m', '.s2p', '.csv', '.png'};
            app.FileExtensionDropDown.Value = '.mdb';
            app.ProjectNameEditFieldLabel = uilabel(app.FilePropertiesPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name';
            app.ProjectNameEditField = uieditfield(app.FilePropertiesPanel, 'text');
            app.ProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditFieldValueChanged, true);
            app.ProjectDirectoryEditFieldLabel = uilabel(app.FilePropertiesPanel);
            app.ProjectDirectoryEditFieldLabel.Text = 'Project Directory';
            app.ProjectDirectoryEditField = uieditfield(app.FilePropertiesPanel, 'text');
            app.ProjectDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectDirectoryEditFieldValueChanged, true);
            app.ProjectDirectoryEditField.Editable = 'off';
            app.BrowseForDirectoryButton = uibutton(app.FilePropertiesPanel, 'push');
            app.BrowseForDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseForDirectoryButtonPushed, true);
            app.BrowseForDirectoryButton.Text = '...';
            app.CreateProjectButton = uibutton(app.FilePropertiesPanel, 'push');
            app.CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProjectButtonPushed, true);
            app.CreateProjectButton.Text = 'Create Project';
            % Show the figure after all components are created
            app.NewProjectManagerApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewProjectApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewProjectManagerApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewProjectManagerApp)
        end
    end
end