classdef PlotDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotData                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        ViewMenu                   matlab.ui.container.Menu
        DataPlotsPanel             matlab.ui.container.Panel
        FrequencyDropDownLabel     matlab.ui.control.Label
        FrequencyDropDown          matlab.ui.control.DropDown
        RollPositionDropDownLabel  matlab.ui.control.Label
        RollPositionDropDown       matlab.ui.control.DropDown
        Image                      matlab.ui.control.Image
        PlotDataLabel              matlab.ui.control.Label
        ToolTipLabel               matlab.ui.control.Label
        AZPlotAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %DataObj{cellIDX}{:,1,:) = Frequency Value
        %DataObj{cellIDX}{:,2,:) = AZ Pos Value
        %DataObj{cellIDX}{:,3,:) = Roll Pos Value
        %DataObj{cellIDX}{:,4,:) = S21 Value
        
        PlotDataAppNameObj = 'Plot Data';
        PlotTitleObj = 'AZ Angle vs. Gain';
        
        CallingAppObj; % Description
        MainAppObj; % Description
        
        AZDegIntervalObj;
        RollDegIntervalObj;
        FreqIntervalObj;
    end
    
    methods (Access = public)
        
        function setWindowTitle(app,newTitle)
           app.PlotData.Name = [newTitle, ' || ',app.PlotDataAppNameObj];
        end
        
        function populateDropDowns(app)
            for i = 1:size(app.MainAppObj.DataToWriteObj{2},1)
                app.FrequencyDropDown.Items = {app.FrequencyDropDown.Items{1:end} num2str(app.MainAppObj.DataToWriteObj{2}(i,1,1))};
            end
            
            for j = 1:size(app.MainAppObj.DataToWriteObj{2},3)
                app.RollPositionDropDown.Items = {app.RollPositionDropDown.Items{1:end} num2str(app.MainAppObj.DataToWriteObj{2}(1,3,j))};
            end
        end
        
        function updatePlot(app, idxOfFreq, idxOfRoll)         
            for i = 2:(length(app.MainAppObj.DataToWriteObj))
              SObj(i - 1,1) = app.MainAppObj.DataToWriteObj{i}(idxOfFreq,4,idxOfRoll); %needs fixing 03.23.20
            end

            updatePlotTitle(app,app.MainAppObj.DataToWriteObj{i}(idxOfFreq,1,idxOfRoll),app.MainAppObj.DataToWriteObj{i}(idxOfFreq,3,idxOfRoll));
            plot(app.AZPlotAxes,app.AZDegIntervalObj,20*log10(abs(SObj(:,1))));
            
        end
        
        function updatePlotTitle(app, freqVal, rollPos)
            
            app.AZPlotAxes.Title.String = [app.PlotTitleObj sprintf('|| Frequency: %.6f | Roll: %.2f',freqVal, rollPos)];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp)
         app.CallingAppObj = callingApp;
         app.MainAppObj = mainApp;
         populateDropDowns(app);
         setWindowTitle(app,app.MainAppObj.ProjectFileFullPathObj)
         pause(0.5);
         app.MainAppObj.setAppOnTop(true,app.PlotData.Name);
         
         AZStartPos = app.MainAppObj.DataToWriteObj{2}(1,2,1);
         AZEndPos = app.MainAppObj.DataToWriteObj{end}(1,2,1);
         app.AZDegIntervalObj = AZStartPos:((abs(AZStartPos)+abs(AZEndPos))/(length(app.MainAppObj.DataToWriteObj) - 2)):AZEndPos;
        
         app.RollDegIntervalObj = app.MainAppObj.DataToWriteObj{2}(1,3,:);
         app.FreqIntervalObj = app.MainAppObj.DataToWriteObj{2}(:,1,1);

         updatePlot(app, 1, 1);
        end

        % Close request function: PlotData
        function PlotDataCloseRequest(app, event)
            app.MainAppObj.PlotDataButton.Enable = 'on';
            
            delete(app)
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            FreqValue = app.FrequencyDropDown.Value;
            RollValue = app.RollPositionDropDown.Value;
            
            [~,indexOfFreq] = find(str2num(FreqValue) == app.FreqIntervalObj);
            [~,indexOfRollDeg] = find(str2num(RollValue) == app.RollDegIntervalObj);
            updatePlot(app,indexOfFreq, indexOfRollDeg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotData = uifigure('Visible', 'off');
            app.PlotData.Name = 'Plot Data';
            app.PlotData.CloseRequestFcn = createCallbackFcn(app, @PlotDataCloseRequest, true);
            app.FileMenu = uimenu(app.PlotData);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.EditMenu = uimenu(app.PlotData);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.PlotData);
            app.ViewMenu.Text = 'View';
            app.DataPlotsPanel = uipanel(app.PlotData);
            app.DataPlotsPanel.Title = 'Data & Plots';
            app.FrequencyDropDownLabel = uilabel(app.DataPlotsPanel);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.DataPlotsPanel);
            app.FrequencyDropDown.Items = {};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = {};
            app.RollPositionDropDownLabel = uilabel(app.DataPlotsPanel);
            app.RollPositionDropDownLabel.Text = 'Roll Position';
            app.RollPositionDropDown = uidropdown(app.DataPlotsPanel);
            app.RollPositionDropDown.Items = {};
            app.RollPositionDropDown.Value = {};
            app.Image = uiimage(app.DataPlotsPanel);
            app.Image.ImageSource = 'plot.svg';
            app.PlotDataLabel = uilabel(app.DataPlotsPanel);
            app.PlotDataLabel.Text = 'Plot Data';
            app.ToolTipLabel = uilabel(app.DataPlotsPanel);
            app.ToolTipLabel.Text = 'Plot measured data with the specified parameters.';
            app.AZPlotAxes = uiaxes(app.DataPlotsPanel);
            title(app.AZPlotAxes, 'AZ Angle vs. Gain')
            xlabel(app.AZPlotAxes, 'Azimuth Angle (Degrees)')
            ylabel(app.AZPlotAxes, 'Gain (dBm)')
            % Show the figure after all components are created
            app.PlotData.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotDataApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotData)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotData)
        end
    end
end