classdef ProjectManagerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProjectManagementApp  matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        EditMenu              matlab.ui.container.Menu
        ViewMenu              matlab.ui.container.Menu
        FileBrowserPanel      matlab.ui.container.Panel
        CurrentProjectTree    matlab.ui.container.Tree
        FolderNode            matlab.ui.container.TreeNode
        Panel2                matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        FolderSelectButton    matlab.ui.control.Button
        NewProjectButton      matlab.ui.control.Button
        CopyProjectButton     matlab.ui.control.Button
        ManualButton          matlab.ui.control.Button
        ExitButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp; % Description
        NewProjectAppObj;
    end
    
    methods (Access = private)
        
        function addFolderToTree(app,folderNode,folderToAdd)
            listOfFiles = dir(folderToAdd);
            folderSize = size(listOfFiles,1);
            
            for idx = 1:folderSize
                drawnow();
                
                if (~startsWith(listOfFiles(idx).name,"."))
                    if (listOfFiles(idx).isdir)
                        newNode = uitreenode(folderNode,'Text',listOfFiles(idx).name);
                        addFolderToTree(app,newNode,[listOfFiles(idx).folder '/' listOfFiles(idx).name])
                    else
                        uitreenode(folderNode,'Text',listOfFiles(idx).name);
                    end
                end
            end
            
        end
        
        function updateFileTree(app,rootFolder)
            delete(app.FolderNode.Children)
            app.FolderNode.Text = rootFolder;
            
            f = uifigure('Name','Directory Indexing');
            fileLoadingProgressBar = uiprogressdlg(f,'Title','Opening Folder and Subfolders. . .','Indeterminate','on');
            pause(.5)
            app.CallingApp.setAppOnTop(true,f.Name);
            addFolderToTree(app,app.FolderNode,rootFolder);
            
            close(fileLoadingProgressBar);
            delete(fileLoadingProgressBar);
            clear fileLoadingProgressBar;
            close(f)
            delete(f)
            clear f;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            pause(0.5);
            app.CallingApp.setAppOnTop(true,app.ProjectManagementApp.Name);
            updateFileTree(app,pwd);            
        end

        % Close request function: ProjectManagementApp
        function ProjectManagementAppCloseRequest(app, event)
            % Enable the Project Management button in main app
            app.CallingApp.ProjectManagementButton.Enable = 'on';
            delete(app.NewProjectAppObj);
            delete(app)
            
        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            app.CallingApp.setAppOnTop(false,app.ProjectManagementApp.Name);
            app.CallingApp.setAppOnTop(false,app.CallingApp.MeasurementApp.Name);
            
            selectedFolder = uigetdir(pwd,'Select a Project Folder');
            app.CallingApp.ProjectDirectoryObj = dir(selectedFolder);
            app.CallingApp.setAppOnTop(true,app.CallingApp.MeasurementApp.Name);
            app.CallingApp.setAppOnTop(true,app.ProjectManagementApp.Name);
            
            app.CallingApp.setWindowTitle(selectedFolder);
            
            updateFileTree(app,selectedFolder);
        end

        % Button pushed function: NewProjectButton
        function NewProjectButtonPushed(app, event)
            app.NewProjectButton.Enable = 'off';
            
            app.NewProjectAppObj = NewProjectApp(app,app.CallingApp);
            app.CallingApp.NewProjectAppObj = app.NewProjectAppObj;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProjectManagementApp = uifigure('Visible', 'off');
            app.ProjectManagementApp.Name = 'Project Management App';
            app.ProjectManagementApp.CloseRequestFcn = createCallbackFcn(app, @ProjectManagementAppCloseRequest, true);
            app.FileMenu = uimenu(app.ProjectManagementApp);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.ProjectManagementApp);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.ProjectManagementApp);
            app.ViewMenu.Text = 'View';
            app.FileBrowserPanel = uipanel(app.ProjectManagementApp);
            app.FileBrowserPanel.Title = 'File Browser';
            app.CurrentProjectTree = uitree(app.FileBrowserPanel);
            app.FolderNode = uitreenode(app.CurrentProjectTree);
            app.FolderNode.Text = 'Node';
            app.Panel2 = uipanel(app.ProjectManagementApp);
            app.Panel2.Title = 'Panel2';
            app.GridLayout = uigridlayout(app.Panel2);
            app.FolderSelectButton = uibutton(app.GridLayout, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = 'Folder Select';
            app.NewProjectButton = uibutton(app.GridLayout, 'push');
            app.NewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewProjectButtonPushed, true);
            app.NewProjectButton.Text = 'New Project';
            app.CopyProjectButton = uibutton(app.GridLayout, 'push');
            app.CopyProjectButton.Text = 'Copy Project';
            app.ManualButton = uibutton(app.GridLayout, 'push');
            app.ManualButton.Text = 'Manual';
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.ProjectManagementApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectManagerApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProjectManagementApp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProjectManagementApp)
        end
    end
end