classdef TakeMeasurementApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TakeMeasurement                matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ViewMenu                       matlab.ui.container.Menu
        DataPlotsPanel                 matlab.ui.container.Panel
        RelativeGainAxis               matlab.ui.control.UIAxes
        ParametersTable                matlab.ui.control.Table
        PauseMeasurementButton         matlab.ui.control.Button
        SetParamsPanel                 matlab.ui.container.Panel
        VNAPanel                       matlab.ui.container.Panel
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        StopEditFieldLabel             matlab.ui.control.Label
        StopEditField                  matlab.ui.control.NumericEditField
        SpanFrequencyCheckBox          matlab.ui.control.CheckBox
        FreqUnitsDropDown              matlab.ui.control.DropDown
        StartFreqUnitsDropDown         matlab.ui.control.DropDown
        StopFreqUnitsDropDown          matlab.ui.control.DropDown
        PositionControllerPanel        matlab.ui.container.Panel
        AzimuthParametersPanel         matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        AZLimitsLabel                  matlab.ui.control.Label
        AZIncrementSizeEditFieldLabel  matlab.ui.control.Label
        AZIncrementSizeEditField       matlab.ui.control.NumericEditField
        AZStartPositionEditFieldLabel  matlab.ui.control.Label
        AZStartPositionEditField       matlab.ui.control.NumericEditField
        AZEndPositionEditFieldLabel    matlab.ui.control.Label
        AZEndPositionEditField         matlab.ui.control.NumericEditField
        RollParametersPanel            matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        RollLimitsLabel                matlab.ui.control.Label
        RollIncrementSizeEditFieldLabel  matlab.ui.control.Label
        RollIncrementSizeEditField     matlab.ui.control.NumericEditField
        RollStartPositionEditFieldLabel  matlab.ui.control.Label
        RollStartPositionEditField     matlab.ui.control.NumericEditField
        RollEndPositionEditFieldLabel  matlab.ui.control.Label
        RollEndPositionEditField       matlab.ui.control.NumericEditField
        FixedPositionCheckBox          matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        OutputConsoleTab               matlab.ui.container.Tab
        OutputConsoleTextArea          matlab.ui.control.TextArea
        ErrorLogTab                    matlab.ui.container.Tab
        ErrorLogTextArea               matlab.ui.control.TextArea
        ManagementPanel                matlab.ui.container.Panel
        EndMeasurementButton           matlab.ui.control.Button
        ExportDataButton               matlab.ui.control.Button
        ProgressBar                    matlab.ui.container.Panel
    end

    
    properties (Access = public)
        CallingAppObj; % Description
        MainAppObj;
        ExportDataOptionsAppObj;
        
        ProjectDirectoryObj;
        ProjectFileObj;
        
        SingleFreqUnitsObj;
        StartFreqUnitsObj;
        StopFreqUnitsObj;
        SpanFreqCheckBoxObj;
        FixedPositionCheckBoxObj;
        IsTestRun;
        
        ControllerObj;
        PositionControllerObj;
        
        RollAxisObj = 2; %Default axis number of the Roll Axis
        AZAxisObj = 1; %Default axis number of the AZ Axis
        
        POSITION_ERROR = 0.6;
        
        VNAFreqPointsObj;
        NumPointsObj;
        SObj;
        MeasuredDataObj = {datestr(now)};
        
        axObj;
        phObj;
        thObj;
        progressBarTextObj;
        
    end
    
    methods (Access = public)
        
        function createProgressBar(app)
            ax = axes(app.ProgressBar,'Position',[.1 .4 .8 .1],'box','on','xtick',[],'ytick',[],...
    'color',[0.65 0.65 0.65],'xlim',[0,1],'ylim',[0,1]);
            title(ax,'Initializing. . .')
            % Create empty patch that will be updated
            ph = patch(ax,[0 0 0 0],[0 0 1 1],[0 1 0]);
            % Create the percent-complete text that will be updated
            th = text(ax,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            
            app.axObj = ax;
            app.phObj = ph;
            app.thObj = th;
             
        end
        
        function updateProgressBar(app,progress,lineIn)
                        
            app.phObj.XData = [0 progress progress 0];
            app.thObj.String = sprintf('%.0f%%',round(progress*100));
           
            title(app.axObj,lineIn);
        end
        
        function startMeasurements(app)
            writeToOutputConsole(app,sprintf('Output Console Started.\n\n'));
            writeToErrorLog(app,sprintf('Error Log Started.\n\n'));
            drawnow();
            
            startFreq = app.StartEditField.Value;
            stopFreq = app.StopEditField.Value;
            
            
            %Calculate the interval for which the position controller will rotate throughout the measurements.
            AZDegInterval = app.AZStartPositionEditField.Value:app.AZIncrementSizeEditField.Value:app.AZEndPositionEditField.Value;
            rollDegInterval = app.RollStartPositionEditField.Value:app.RollIncrementSizeEditField.Value:app.RollEndPositionEditField.Value;
            drawnow();
            
            %Connect to the VNA and Position Controller
            connectToVNA(app,app.ControllerObj);
            connectToPositionController(app,app.ControllerObj);
            
            %Query the position of the Axis (AZ)
            AZCurrPos = getCurrPos(app,app.ControllerObj, app.AZAxisObj);
            
            %Query the position of the Axis (ROLL)
            rollCurrPos = getCurrPos(app,app.ControllerObj,app.RollAxisObj);
    
            %Command AZ and Roll Axes to move to their starting positions
            moveToPosition(app,app.ControllerObj,app.AZAxisObj,app.AZStartPositionEditField.Value,AZCurrPos);
            moveToPosition(app,app.ControllerObj,app.RollAxisObj,app.RollStartPositionEditField.Value,rollCurrPos);
            rollCurrPos = getCurrPos(app,app.ControllerObj,app.RollAxisObj);
            
            %Set VNA Frequency to either Single frequency or Span
            %frequency. In either case, the start and stop frequencies must
            %be set, so the same function is used.
            if (app.SpanFrequencyCheckBox.Value)
                setVNAFreq(app,app.StartEditField.Value,app.StopEditField.Value,app.StartFreqUnitsDropDown.Value,app.StopFreqUnitsDropDown.Value);
                writeToOutputConsole(app,sprintf('Beginning Span Frequency Measurements. . .\n\n'))
                numPoints = getVNANumPoints(app,app.ControllerObj);
                
                app.VNAFreqPointsObj = 1e5 * round((startFreq:(stopFreq - startFreq) / (numPoints - 1):stopFreq) / 1e5);
            else
                setVNAFreq(app,app.FrequencyEditField.Value,app.FrequencyEditField.Value,app.FreqUnitsDropDown.Value,app.FreqUnitsDropDown.Value);
                writeToOutputConsole(app,sprintf('Beginning Single Frequency Measurements. . .\n\n'))
            end
            
            calculateVNAParameters(app,app.ControllerObj)            
            app.TotalIterationsObj = 0;
            %Loop through each degree in the interval and record data for
            %the frequency type specified by the user in the previous
            %window
            for AZCurrentDegree = AZDegInterval
                
                %Calculate the current iteration of the loop
                [~,AZLoopIdx] = find(AZDegInterval == AZCurrentDegree);
                AZAnglesRemaining = length(AZDegInterval) - AZLoopIdx;
                
                app.SObj(:,2,AZLoopIdx) = AZCurrentDegree * ones(length(app.VNAFreqPointsObj),1);
                app.SObj(:,3,AZLoopIdx) = rollCurrPos * ones(length(app.VNAFreqPointsObj),1);
                
                writeToOutputConsole(app,sprintf('Current Azimuth Degree: %.2f\n\n',AZCurrentDegree));
                drawnow();
                
                if (~app.FixedPositionCheckBoxObj)
                    
                    for rollCurrentDegree = rollDegInterval
                        
                        app.TotalIterationsObj = app.TotalIterationsObj + 1;
                        
                        %Calculate the current iteration of the loop
                        [~,rollLoopIdx] = find(rollDegInterval == rollCurrentDegree);
                        rollAnglesRemaining = length(rollDegInterval) - rollLoopIdx;
                        
                        loadBarProgress = app.TotalIterationsObj/(length(rollDegInterval) * length(AZDegInterval));
                        
                        app.SObj(:,2,rollLoopIdx) = AZCurrentDegree * ones(length(app.VNAFreqPointsObj),1);
                        app.SObj(:,3,rollLoopIdx) = rollCurrentDegree * ones(length(app.VNAFreqPointsObj),1);
                        
                        writeToOutputConsole(app,sprintf('Current Roll Degree: %.2f\n\n',rollCurrentDegree));
                        drawnow();
                        
                        %Determine if the axis is in the correct position, if not,
                        %command it to move to the correct position.
                        AZInPosition = verifyIfInPosition(app,app.ControllerObj,app.AZAxisObj,AZCurrentDegree,app.POSITION_ERROR,'v');
                        rollInPosition = verifyIfInPosition(app,app.ControllerObj,app.RollAxisObj,rollCurrentDegree,app.POSITION_ERROR,'v');
                        
                        %Check to see if the axis is idle so that we can take
                        %measurements while it is stationary.
                        AZCurrVel = getCurrVelocity(app,app.ControllerObj,app.AZAxisObj);
                        AZIdle = verifyIfIdle(app,app.ControllerObj,AZCurrVel,app.AZAxisObj);
                        rollCurrVel = getCurrVelocity(app,app.ControllerObj,app.RollAxisObj);
                        rollIdle = verifyIfIdle(app,app.ControllerObj,rollCurrVel,app.RollAxisObj);
                        
                        %If the axis is idle, we can proceed.
                        if (AZIdle && AZInPosition && rollIdle && rollInPosition) %AZ axis is stopped and ready to capture data
                            
                            %Record data
                            writeToOutputConsole(app,sprintf('Taking measurement at AZ:%.2f degrees, Roll:%.2f degrees\n',getCurrPos(app,app.ControllerObj,app.AZAxisObj),getCurrPos(app,app.ControllerObj,app.RollAxisObj)));
                            updateProgressBar(app,loadBarProgress,sprintf('Taking measurement at AZ:%.2f degrees, Roll:%.2f degrees\n',getCurrPos(app,app.ControllerObj,app.AZAxisObj),getCurrPos(app,app.ControllerObj,app.RollAxisObj)));
                            drawnow();
                            if (app.SpanFreqCheckBoxObj)
                                                       
                                takeSpanFrequencyMeasurement(app,app.ControllerObj,rollLoopIdx)
                            
                            else
                                takeSingleFrequencyMeasurement(app,app.ControllerObj,rollLoopIdx);
                                
                            end
                            
                            app.ControllerObj.Timeout = 1; % Reset GPIB Controller Timeout to 1 since it was changed during measurement
                            
                            if (rollCurrentDegree ~= rollDegInterval(end))
                                writeToOutputConsole(app,sprintf('Incrementing Roll Position by %.2f degrees\n',app.RollIncrementSizeEditField.Value));
                                drawnow();
                                incrementByDegree(app,app.ControllerObj,app.RollAxisObj,app.RollIncrementSizeEditField.Value);
                            else
                                if (AZCurrentDegree ~= AZDegInterval(end))
                                  writeToOutputConsole(app,sprintf('Resetting Roll Position for next Azimuth Position\n'));
                                else
                                  writeToOutputConsole(app,sprintf('Resetting Roll Position for next set of measurements.\n'));  
                                end
                                drawnow();
                                moveToPosition(app,app.ControllerObj,app.RollAxisObj,app.RollStartPositionEditField.Value);
                            
                            end
                        end
                    end
                    
                else
                    app.TotalIterationsObj = app.TotalIterationsObj + 1;
                    loadBarProgress = app.TotalIterationsObj / length(AZDegInterval);
                    writeToOutputConsole(app,sprintf('Taking measurement at AZ:%.2f degrees.\n',getCurrPos(app,app.ControllerObj,app.AZAxisObj)));
                    updateProgressBar(app,loadBarProgress,sprintf('Taking measurement at AZ:%.2f degrees.\n',getCurrPos(app,app.ControllerObj,app.AZAxisObj)));
                    drawnow();
                    if (app.SpanFreqCheckBoxObj)
                                               
                        takeSpanFrequencyMeasurement(app,app.ControllerObj,rollLoopIdx)
                    
                    else
                        takeSingleFrequencyMeasurement(app,app.ControllerObj,rollLoopIdx);
                        
                    end
                    
                    app.ControllerObj.Timeout = 1; % Reset GPIB Controller Timeout to 1 since it was changed during measurement
                    
                end
                
                %store the 3D matrix from the roll loop into the end data
                %cell array here
                app.MeasuredDataObj = {app.MeasuredDataObj{1:end} app.SObj};
                
                if (AZCurrentDegree ~= AZDegInterval(end))
                    writeToOutputConsole(app,sprintf('Incrementing Azimuth Position by %.2f degrees\n',app.AZIncrementSizeEditField.Value));
                    drawnow();
                    incrementByDegree(app,app.ControllerObj,app.AZAxisObj,app.AZIncrementSizeEditField.Value);
                else
                    writeToOutputConsole(app,sprintf('Done with current set of measurements!\n'));            
                end
                
            end
            
            %Once measurements are done, process the data and store to file
            saveMeasurementData(app);
            
            %take measurements:
                %connect to vna and position controller -
                %command position controller to move to start position while the rest of setup is happening --
                %set vna freq to either freq or span freqs ---
                %send signal (pretty much happens when you set the vna freqs)
                    %if single freq measurement:
                        %take measurement
                    %if span freq measurement:
                        %span all frequencies at position
                        %take measurement - same as above, but a new file
                        %for each frequency in the span.
                    %increment position
                    %iterate
                %store all data
                    %save file with 2 columns 's11 s21' and as many
                    %rows as degree increments they had, with the
                    %values for s11 and s21 in each row:
                    %    s11   s21
                    %-90|xxx   xxx
                    %-89|xxx   xxx
                    %-88|xxx   xxx
                %terminate communcation with all instruments
                %plot data and enable export data button
            
        end

        function numPoints = getVNANumPoints(app,VNA)
            fprintf(VNA, '++addr 16');
            
            fprintf(VNA, 'POIN?');
            numPoints = char(fread(VNA, 30))';
            numPoints = str2num(numPoints(1:24));
            app.NumPointsObj = numPoints;           
        
        end
        
        function createS2PFile(app,currentDegree)
            sParams = reshape(transpose(S),2,2,app.NumPointsObj);
            outputFile = [app.MainAppObj.ProjectFileObj '_' currentDegree];
            % Configure the PDF output file
            set(gca,'units','centimeters');
            width = 25;
            height = 10;
            set(gcf, 'PaperUnits','centimeters');
            set(gcf, 'PaperSize', [width height]);
            set(gcf, 'PaperPositionMode', 'manual');
            set(gcf, 'PaperPosition',[0 0 width height]);
            
            % Write the PDF file to the current directory (change the file name as
            % desired)
            print(gcf,strcat(outputFile,'.pdf'),'-dpdf','-r300')
            
            % Write the .s2p file(change the file name as desired)
            rfwrite(sParams, app.VNAFreqPointsObj', strcat(outputFile,'.s2p'),'Format','DB','FrequencyUnit', 'Hz');
            
            % Open the .s2p file and read all of the contents
            fid = fopen(strcat(outputFile,'.s2p'),'r');
            s2p = textscan(fid,'%s','Delimiter','\n');
            fclose(fid);
            
            % Comments for the s2p file
            VNAmodel = 'HP 8752C Vector Network Analyzer';
            dateCreated = ['Date: ' datestr(datetime)];
            sParamComment = ['S-Parameter: S11, S21'];
            startFreqComment = ['Start Frequency: ' num2str(app.StartEditField.Value / 1e6) ' MHz'];
            stopFreqComment = ['Stop Frequency: ' num2str(app.StopEditField.Value / 1e6) ' MHz'];
            numPointsComment = ['Number of Points: ' num2str(app.NumPointsObj)];
            
            comments = {VNAmodel; startFreqComment; sParamComment; stopFreqComment; numPointsComment; dateCreated};
            
            % Add the ! to the beginning of the comments to conform with the
            % Touchstone .s2p file format
            for c = 1:length(comments)
                comments{c} = ['! ' comments{c}];
            end
            
            % Add the comments from the .csv file to the .s2p file
            s2p = [s2p{1}(1);comments;s2p{1}(2:end)];
            
            % Open the file for writing, which erases its contents and then write
            % the data with comments included to the .s2p file
            fid = fopen(strcat(outputFile,'.s2p'),'w');
            fprintf(fid,'%s\n',s2p{:});
            fclose(fid);
            
            fprintf('\n%s\n  %s\n  %s\n','Files written:',strcat(pwd,'/',outputFile,'.s2p'),strcat(pwd,'/',outputFile,'.pdf'))
        end
        
        function setVNAFreq(app,desiredStartFreq, desiredStopFreq, startFreqUnits, stopFreqUnits)
            fprintf(app.ControllerObj, '++addr 16'); %address of VNA
            fprintf(app.ControllerObj, sprintf('STAR %f %s',desiredStartFreq,startFreqUnits));
            fprintf(app.ControllerObj, sprintf('STOP %f %s',desiredStopFreq,stopFreqUnits));
            fprintf(app.ControllerObj, '++addr 4'); %address of MI4190 Position Controller
            
            writeToOutputConsole(app,sprintf('Set VNA Start Freq: %.6f %s, Stop Freq: %.6f %s\n\n',desiredStartFreq,startFreqUnits,desiredStopFreq,stopFreqUnits))
        end
        
        function takeSingleFrequencyMeasurement(app,VNA,rollLoopIdx)
              
            fprintf(app.ControllerObj, '++addr 16');
            fprintf(VNA, 'POIN 3'); %smallest allowable amount of points, we only need 1.
            sNames = ['S11';'S21'];
            
            for n = 1:length(sNames(:,1))
                fprintf(VNA,sNames(n,:));
                pause(2);
                    
                % Perform a single sweep and pause to give time for a single sweep to 
                % complete. This may need to be adjusted based on the frequency span, 
                % number of points, IF bandwidth, and averaging
                fprintf(VNA, 'SING');
                pause(4);
            
                % Output the data
                
                writeToOutputConsole(app,sprintf('Transferring: %s. . .\n', sNames(n,:)));
                fprintf(VNA, 'OUTPDATA');
            
                dataTran = char(fread(VNA))';
                writeToOutputConsole(app,sprintf('Transferring Data Completed. . .\n'));
            
                % Convert character data to numbers
                dataNums = textscan(dataTran,'%f%f','Delimiter',',');
            
                S(:,n) = dataNums{1} + j*dataNums{2}; 
                
            end           
            app.SObj(:,4,rollLoopIdx) = S(1,2);
            app.SObj(:,1,rollLoopIdx) = app.FrequencyEditField.Value;
            % Set to sweep continuously
            fprintf(VNA, 'CONT');
                       
        end
        
        function takeSpanFrequencyMeasurement(app,VNA,rollLoopIdx)
              
            fprintf(app.ControllerObj, '++addr 16');
            sNames = ['S11';'S21'];
            
            for n = 1:length(sNames(:,1))
                fprintf(VNA,sNames(n,:));
                pause(2);
                    
                % Perform a single sweep and pause to give time for a single sweep to 
                % complete. This may need to be adjusted based on the frequency span, 
                % number of points, IF bandwidth, and averaging
                fprintf(VNA, 'SING');
                pause(4);
            
                % Output the data
                
                writeToOutputConsole(app,sprintf('Transferring: %s. . .\n', sNames(n,:)));
                fprintf(VNA, 'OUTPDATA');
            
                dataTran = char(fread(VNA))';
                writeToOutputConsole(app,sprintf('Transferring Data Completed. . .\n'));
            
                % Convert character data to numbers
                dataNums = textscan(dataTran,'%f%f','Delimiter',',');
            
                S(:,n) = dataNums{1} + j*dataNums{2}; 
                
            end
            app.SObj(:,4,rollLoopIdx) = S(:,2);
            app.SObj(:,1,rollLoopIdx) = app.VNAFreqPointsObj';
            % Set to sweep continuously
            fprintf(VNA, 'CONT');
                       
        end
        
        function moveToPosition(app,MI4190,axisToMove,desiredPos,AxisCurrPos)
            fprintf(MI4190, '++addr 4');
            AxisCurrVel = getCurrVelocity(app,app.ControllerObj,axisToMove);
            %updateLamps(AZCurrVel,measApp,false,(AZCurrVel >= 0.5) || (AZCurrVel <= -0.5));
            
            if ((AxisCurrVel >= -0.01) && (AxisCurrVel <= 0.01))
                
               % updateLamps(AZCurrVel,measApp,false,true);
                
                writeToOutputConsole(app,sprintf('Moving Axis (%d) from position: %.2f, to desired position: %.2f\n',axisToMove, AxisCurrPos, desiredPos));
               % measApp.updateProgressBar(loadBarProgress,sprintf('Moving Axis (AZ) from position: %.2f, to desired position: %.2f',AZCurrPos, desiredPos));
                
                fprintf(MI4190, 'CONT1:AXIS(%d):POS:COMM %f\n', axisToMove, desiredPos);
                fprintf(MI4190, 'CONT1:AXIS(%d):MOT:STAR', axisToMove);                                
            else
                writeToErrorLog(app,sprintf('[WARNING] Axis is in motion and cannot be moved!\n'));
               % updateLamps(AZCurrVel,measApp,false,(AZCurrVel >= 0.5) || (AZCurrVel <= -0.5));
               % measApp.updateProgressBar(loadBarProgress,sprintf('Warning: Axis is in motion and cannot be moved!'));
            end    

        end
        
        function incrementByDegree(app,MI4190,axisToIncrement,incrementAmount)
            fprintf(MI4190, '++addr 4');
            
           % updateLamps(1,measApp,false,true);
            fprintf(MI4190, 'CONT1:AXIS(%d):POS:INCR %.2f\n', axisToIncrement, incrementAmount);
            fprintf(MI4190, 'CONT1:AXIS(%d):MOT:STAR',axisToIncrement);
        end
        
        function AxisCurrPos = getCurrPos(app,MI4190,axisToQuery)
        fprintf(MI4190, '++addr 4');
            
        fprintf(MI4190, 'CONT1:AXIS(%d):POS:CURR?',axisToQuery);
        AxisCurrPosChar = char(fread(MI4190, 100))';
        AxisCurrPos = str2double(convertCharsToStrings(AxisCurrPosChar));
       
       % measApp.StatusTable.Data{1,1} = AZCurrPos;
       % measApp.rotateHornImage(AZCurrPos);
       % measApp.CurrentAngleGuage.Value = AZCurrPos;
        end
        
        function AxisCurrVel = getCurrVelocity(app,MI4190,axisToQuery)
            fprintf(MI4190, '++addr 4');
            
            fprintf(MI4190, 'CONT1:AXIS(%d):VEL:CURR?',axisToQuery);
            AxisCurrVelChar = char(fread(MI4190, 100))';
            AxisCurrVel = str2double(convertCharsToStrings(AxisCurrVelChar));
            
           % measApp.StatusTable.Data{3,1} = AZCurrVelDoub;
           % updateLamps(AZCurrVelDoub,measApp,false,(AZCurrVelDoub >= 0.5) || (AZCurrVelDoub <= -0.5));
            
        end
        
        function axisIdle = verifyIfIdle(app,MI4190,AxisCurrVel)
            fprintf(MI4190, '++addr 4');
            
            while (AxisCurrVel ~= 0.0000)
                axisIdle = false;
                pause(1);
                AxisCurrVel = getAZCurrVelocity(app,MI4190);
            end
                axisIdle = true;
        end
        
        function stopMotion(app,MI4190,axisToStop)
            fprintf(MI4190, '++addr 4');
            
            writeToOutputConsole(app,sprintf('Stopping Axis Motion\n'));
           % measApp.updateProgressBar(loadBarProgress,sprintf('Stopping Axis Motion'));
            fprintf(MI4190, 'CONT1:AXIS(%d):MOT:STOP',axisToStop);
        end
        
        function axisInPosition = verifyIfInPosition(app,MI4190,axisToVerify,desiredPosition,positionError,verbose)
        
        fprintf(MI4190, '++addr 4');
        
        if (nargin < 5)
            
            verbose = 'a';
        
        end
        
        desiredPosMin = desiredPosition - positionError;
        desiredPosMax = desiredPosition + positionError;
        axisInPosition = false;
        
        tic
            while toc<=80
                  
                  if (verbose == 'v')
                    writeToOutputConsole(app,sprintf('Verifying if Axis is in Position. . .\n'));
                   % measApp.updateProgressBar(loadBarProgress,sprintf('Verifying if Axis is in Position'));
                  end
                  
                  AxisCurrPos = getCurrPos(app,MI4190,axisToVerify);
                  AxisCurrVel = getCurrVelocity(app,MI4190,axisToVerify);          
                  drawnow();
                  
                  if ((AxisCurrPos >= desiredPosMin) && (AxisCurrPos <= desiredPosMax))
                      if (verbose == 'v')
                       % measApp.updateProgressBar(loadBarProgress,sprintf('Axis (AZ) is in desired position. Continuing...'));
                        writeToOutputConsole(app,sprintf('Axis (%d) is in desired position: %.2f. Time elapsed: %.2f seconds.\n', axisToVerify, AxisCurrPos, toc'));
                       % measApp.StatusTable.Data{2,1} = measApp.StatusTable.Data{1,1} + incrementSize;
                      end
                      axisInPosition = true;
                      break;
                  
                  elseif (AxisCurrVel == 0.00)
                      moveToPosition(app,MI4190,axisToVerify,desiredPosition,AxisCurrPos);
                  end
            end
            
           % updateLamps(AZCurrVel,measApp,false,(AZCurrVel >= 0.5) || (AZCurrVel <= -0.5));
            
            if(~axisInPosition && (AxisCurrVel == 0.00))
                moveAxisToPosition(app,MI4190,axisToVerify,desiredPosition,AxisCurrPos);
            end
        end
        
        function writeToOutputConsole(app,lineIn)
            app.OutputConsoleTextArea.Value = [sprintf('[%s] ', datestr(now,'HH:MM:SS.FFF')) lineIn;app.OutputConsoleTextArea.Value];
        end
        
        function writeToErrorLog(app,lineIn)
            app.ErrorLogTextArea.Value = [sprintf('[%s] ', datestr(now,'HH:MM:SS.FFF')) lineIn;app.ErrorLogTextArea.Value];
        end
        
        function calculateVNAParameters(app,VNA)
           % writeToOutputConsole(app,sprintf('Connecting to VNA. . .\n\n'));
            
            fprintf(VNA, '++addr 16'); %Primary Address of HP8752C VNA
                        
            % Read the start/stop frequencies and number of points from the VNA:
            fprintf(VNA, 'STAR?');
            startFreq = char(fread(VNA, 30))';
            startFreq = str2num(startFreq(1:24));
            
            fprintf(VNA, 'STOP?');
            stopFreq = char(fread(VNA, 30))';
            stopFreq = str2num(stopFreq(1:24));
            
            fprintf(VNA, 'POIN?');
            numPoints = char(fread(VNA, 30))';
            numPoints = str2num(numPoints(1:24));
            app.NumPointsObj = numPoints;
            
            fprintf(VNA, 'SCAL?');
            SCAL = char(fread(VNA, 30))';
            SCAL = str2num(SCAL(1:24));
            
            fprintf(VNA, 'REFP?');
            REFP = char(fread(VNA, 30))';
            REFP = str2num(REFP(1:24));
            
            fprintf(VNA, 'REFV?');
            REFV = char(fread(VNA, 30))';
            REFV = str2num(REFV(1:24));
             
            app.ParametersTable.Data = {startFreq,stopFreq,numPoints,SCAL,REFP,REFV};
            drawnow();
            
            % Compute the expected frequency points from the VNA. The 8720B has a 100
            % KHz frequency resolution, so round to this.
            if (app.SpanFreqCheckBoxObj.Value)
              app.VNAFreqPointsObj = 1e5 * round((startFreq:(stopFreq - startFreq) / (numPoints - 1):stopFreq) / 1e5);
            else
              app.VNAFreqPointsObj = 1e5 * round((startFreq:(stopFreq - startFreq + 1) / (numPoints - 1):stopFreq) / 1e5);
            end
            % Set the output data format
            fprintf(VNA, 'FORM4');
            
            % Increase the timeout to give enough time for data transfer
            % This is based on the number of points set on the VNA
            % The following emperical formula is approximate
            VNA.Timeout = ceil(numPoints/100*0.5);
        end
        
        function connectToVNA(app,VNA)
            writeToOutputConsole(app,sprintf('Connecting to VNA. . .\n\n'));
            fprintf(VNA, '++addr 16'); %Primary Address of MI4190 Position Controller
            
            fprintf(VNA, '*idn?');
            idn = char(fread(VNA, 100))';
            
            writeToOutputConsole(app,sprintf('VNA ID: %s',idn));
            drawnow();
        end
        
        
        function connectToPositionController(app,MI4190)
            writeToOutputConsole(app,sprintf('Connecting to Position Controller. . .\n\n'));
            fprintf(MI4190, '++addr 4'); %Primary Address of MI4190 Position Controller
            
            fprintf(MI4190, '*idn?');
            idn = char(fread(MI4190, 100))';
            
            writeToOutputConsole(app,sprintf('Position Controller ID: %s',idn));
            drawnow();
        end
        
        function initializeGPIBController(app)
              %===================================SETUP=================================%
            % Specify the virtual serial port created by USB driver. It is currently
            % configured to work for a Mac, so if a PC is being used this will need to
            % be changed (e.g., to a port such as COM3)
            if isunix GPIB = serial('/dev/ttyUSB0'); end                % Linux Serial
            %GPIB = tcpip('192.168.10.6', 1234);            % Linux Ethernet
            if isunix system('sudo chmod 666 /dev/ttyUSB0'); end          
                %if it does not work on linux, you may have to run the command:
                % 'sudo chmod 666 /dev/ttyUSB0' to enable permissions.
            if ismac GPIB = serial('/dev/tty.usbserial-PX2DN8ZM'); end % Mac
            if ispc GPIB = serial('COM3'); end                       % PC


            % Prologix Controller 4.2 requires CR as command terminator, LF is
            % optional. The controller terminates internal query responses with CR and
            % LF. Responses from the instrument are passed through as is. (See Prologix
            % Controller Manual)
            GPIB.Terminator = 'CR/LF';
            
            % Reduce timeout to 0.5 second (default is 10 seconds)
            GPIB.Timeout = 1;
            
            % Set input buffer to be large enough for trace data to be transferred
            % The largest data set is 1601 points and this requires about 100 kB
            GPIB.InputBufferSize = 100000;
            
            % Open virtual serial port
            fclose(GPIB);
            try 
                fopen(GPIB);
            catch
                fprintf(2,"[%s] No Serial device connected. Please reconnect the GPIB adapter and try again.\n", datestr(now,'HH:MM:SS'));
                TakeMeasurementCloseRequest(app);
                error();
            end
            app.ControllerObj = GPIB;
                        
            pause(1)
            
            warning('off','MATLAB:serial:fread:unsuccessfulRead');
                        
            % Configure as Controller (++mode 1), instrument address 4, and with
            % read-after-write (++auto 1) enabled
            fprintf(app.ControllerObj, '++mode 1');
            fprintf(app.ControllerObj, '++addr 16'); %Primary Address of HP8752C VNA
            fprintf(app.ControllerObj, '++auto 1');
            fprintf(app.ControllerObj, '++eoi 1');
                        
            % Read the id of the Controller to verify connection:
            %fprintf(app.ControllerObj, '*idn?');
            %idn = char(fread(app.ControllerObj, 100))';
            %writeToOutputConsole(app,sprintf('VNA ID: %s\n',idn));
            
        end
        
        
        function saveMeasurementData(app)
            writeToOutputConsole(app,sprintf('Saving data to .mat file. . .\n\n'));
            app.MainAppObj.DataToWriteObj = app.MeasuredDataObj;
            dataToWrite = app.MainAppObj.DataToWriteObj;
            
            save([app.MainAppObj.ProjectFileFullPathObj '.mat'],'dataToWrite');
             
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, mainApp, AZIncrementSize, AZStartPos, AZEndPos, RollIncrementSize, RollStartPos, RollEndPos, freqValue, freqUnits, startFreqUnits, stopFreqUnits, startFreq, stopFreq, spanFreq, testRun, fixedRollPosition)
            
            %Assign a reference to the calling app (NewMeasurementApp.mlapp)
            %to CallingAppObj and a reference to the main app (measApp.mlapp)
            %to MainAppObj.
            app.CallingAppObj = callingApp;
            app.MainAppObj = mainApp;
            createProgressBar(app);
            
            if(isempty(app.MainAppObj.ProjectDirectoryObj))
                if (isunix || ismac) projectDir = [cd '/MeasuredData']; end
                if (ispc) projectDir = [cd '\MeasuredData']; end
                
                if ~exist(projectDir, 'dir')
                   mkdir(projectDir);
                   addpath(projectDir);
                end
                
                app.MainAppObj.ProjectDirectoryObj = projectDir;
                app.MainAppObj.ProjectDirectoryNameObj = projectDir;
                
            end
            
            if(isempty(app.MainAppObj.ProjectFileObj))
                projectFile = ['newMeasurement_' datestr(now,'mm_DD_YYYY') '-' datestr(now,'HH-MM-SS')];
                app.MainAppObj.ProjectFileObj = projectFile;
                
                if (isunix || ismac) app.MainAppObj.ProjectFileFullPathObj = [app.MainAppObj.ProjectDirectoryObj '/' app.MainAppObj.ProjectFileObj]; end
                if ispc app.MainAppObj.ProjectFileFullPathObj = [app.MainAppObj.ProjectDirectoryObj '\' app.MainAppObj.ProjectFileObj]; end
            end
            
            %Store the values of the drop downs and check boxes in case the
            %user tries to change their values during measurements.
            %Primarily a visual fix.
            app.SingleFreqUnitsObj = freqUnits;
            app.StartFreqUnitsObj = startFreqUnits;
            app.StopFreqUnitsObj = stopFreqUnits;
            app.SpanFreqCheckBoxObj = spanFreq;
            app.IsTestRun = testRun;
            
            %Bring the values of all the parameters over from the
            %NewMeasurementApp.
            app.AZIncrementSizeEditField.Value = AZIncrementSize;
            app.AZStartPositionEditField.Value = AZStartPos;
            app.AZEndPositionEditField.Value = AZEndPos;
            
            
            app.RollIncrementSizeEditField.Value = RollIncrementSize;
            app.RollStartPositionEditField.Value = RollStartPos;
            app.RollEndPositionEditField.Value = RollEndPos;
            app.FixedPositionCheckBoxObj = fixedRollPosition;
            app.FixedPositionCheckBox.Value = fixedRollPosition;
            
            
            %If the Span Frequency checkbox is ticked, then the start and
            %stop fields must be enabled, and the single frequency field
            %must be disabled.
            if (spanFreq)
                app.StartEditField.Enable = 'on';
                app.StopEditField.Enable = 'on';
                app.StartEditFieldLabel.Enable = 'on';
                app.StopEditFieldLabel.Enable = 'on';
                app.StartFreqUnitsDropDown.Enable = 'on';
                app.StopFreqUnitsDropDown.Enable = 'on';
                app.FrequencyEditField.Enable = 'off';
                app.FrequencyEditFieldLabel.Enable = 'off';
                app.FreqUnitsDropDown.Enable = 'off';
            end
            
            if (fixedRollPosition) 
                app.RollIncrementSizeEditField.Enable = 'off';
                app.RollStartPositionEditField.Enable = 'off';
                app.RollEndPositionEditField.Enable = 'off';
                app.RollIncrementSizeEditFieldLabel.Enable = 'off';
                app.RollStartPositionEditFieldLabel.Enable = 'off';
                app.RollEndPositionEditFieldLabel.Enable = 'off';
                app.RollIncrementSizeEditField.Value = 0; 
            end
            
            app.FrequencyEditField.Value = freqValue;
            app.FreqUnitsDropDown.Value = freqUnits;
            app.StartEditField.Value = startFreq;
            app.StopEditField.Value = stopFreq;
            app.SpanFrequencyCheckBox.Value = spanFreq;
            
            pause(0.5);
            
            %Set this window to be on top
            app.MainAppObj.setAppOnTop(true,app.TakeMeasurement.Name);
            
           if (~testRun)
               
               try 
                   initializeGPIBController(app);  
               catch
                   return;
               end
               drawnow();
               
               %Begin taking measurements
               startMeasurements(app);
           else
               writeToOutputConsole(app,sprintf('Beginning Test Run. . .\n\n'));
               writeToErrorLog(app,sprintf('Beginning Test Run. . .\n\n'));
               app.ExportDataButton.Enable = 'on';
           end
           
                   
        end

        % Value changed function: SpanFrequencyCheckBox
        function SpanFrequencyCheckBoxValueChanged(app, event)
            app.SpanFrequencyCheckBox.Value = app.SpanFreqCheckBoxObj;
        end

        % Button pushed function: EndMeasurementButton
        function EndMeasurementButtonPushed(app, event)
            app.EndMeasurementButton.Enable = 'off';
            app.MainAppObj.ExportDataButton.Enable = 'off';
            
            app.ExportDataOptionsAppObj = ExportDataOptionsApp(app,app.MainAppObj);
            app.MainAppObj.ExportDataOptionsAppObj = app.ExportDataOptionsAppObj;
            
            app.TakeMeasurementCloseRequest(app);
        end

        % Close request function: TakeMeasurement
        function TakeMeasurementCloseRequest(app, event)
            if (exist('app.ControllerObj','var'))
               fclose(app.ControllerObj);
               delete(app.ControllerObj);
               clear app.ControllerObj;
            end
            
            if (~app.IsTestRun)
               
                saveMeasurementData(app);
            end
            delete(app);
        end

        % Value changed function: FreqUnitsDropDown, 
        % StartFreqUnitsDropDown, StopFreqUnitsDropDown
        function FreqUnitsDropDownValueChanged(app, event)
            app.FreqUnitsDropDown.Value = app.SingleFreqUnitsObj;
            app.StartFreqUnitsDropDown.Value = app.StartFreqUnitsObj;
            app.StopFreqUnitsDropDown.Value = app.StopFreqUnitsObj;
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            saveMeasurementData(app);
        end

        % Value changed function: FixedPositionCheckBox
        function FixedPositionCheckBoxValueChanged(app, event)
            app.FixedPositionCheckBox.Value = app.FixedPositionCheckBoxObj;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TakeMeasurement = uifigure('Visible', 'off');
            app.TakeMeasurement.Name = 'Measurement & Automation';
            app.TakeMeasurement.CloseRequestFcn = createCallbackFcn(app, @TakeMeasurementCloseRequest, true);
            app.FileMenu = uimenu(app.TakeMeasurement);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.EditMenu = uimenu(app.TakeMeasurement);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.TakeMeasurement);
            app.ViewMenu.Text = 'View';
            app.DataPlotsPanel = uipanel(app.TakeMeasurement);
            app.DataPlotsPanel.Title = 'Data & Plots';
            app.RelativeGainAxis = uiaxes(app.DataPlotsPanel);
            title(app.RelativeGainAxis, '')
            xlabel(app.RelativeGainAxis, 'AZ Pos (Degrees)')
            ylabel(app.RelativeGainAxis, 'Gain (dBm)')
            app.ParametersTable = uitable(app.DataPlotsPanel);
            app.ParametersTable.ColumnName = {'Start Freq'; 'Stop Freq'; 'Num Points'; 'Scale Factor'; 'Ref Position'; 'Ref Line Value'};
            app.ParametersTable.RowName = {};
            app.PauseMeasurementButton = uibutton(app.DataPlotsPanel, 'push');
            app.PauseMeasurementButton.Text = 'Pause Measurement';
            app.SetParamsPanel = uipanel(app.TakeMeasurement);
            app.SetParamsPanel.Title = 'Parameters For Measurement';
            app.VNAPanel = uipanel(app.SetParamsPanel);
            app.VNAPanel.Title = 'VNA';
            app.FrequencyEditFieldLabel = uilabel(app.VNAPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.VNAPanel, 'numeric');
            app.FrequencyEditField.ValueDisplayFormat = '%11.6f';
            app.FrequencyEditField.Editable = 'off';
            app.StartEditFieldLabel = uilabel(app.VNAPanel);
            app.StartEditFieldLabel.Enable = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StartEditField.ValueDisplayFormat = '%11.6f';
            app.StartEditField.Editable = 'off';
            app.StartEditField.Enable = 'off';
            app.StopEditFieldLabel = uilabel(app.VNAPanel);
            app.StopEditFieldLabel.Enable = 'off';
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.VNAPanel, 'numeric');
            app.StopEditField.ValueDisplayFormat = '%11.6f';
            app.StopEditField.Editable = 'off';
            app.StopEditField.Enable = 'off';
            app.SpanFrequencyCheckBox = uicheckbox(app.VNAPanel);
            app.SpanFrequencyCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpanFrequencyCheckBoxValueChanged, true);
            app.SpanFrequencyCheckBox.Text = ' Span Frequency';
            app.FreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.FreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.FreqUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqUnitsDropDownValueChanged, true);
            app.FreqUnitsDropDown.Value = 'GHz';
            app.StartFreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.StartFreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StartFreqUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqUnitsDropDownValueChanged, true);
            app.StartFreqUnitsDropDown.Enable = 'off';
            app.StartFreqUnitsDropDown.Value = 'GHz';
            app.StopFreqUnitsDropDown = uidropdown(app.VNAPanel);
            app.StopFreqUnitsDropDown.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StopFreqUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqUnitsDropDownValueChanged, true);
            app.StopFreqUnitsDropDown.Enable = 'off';
            app.StopFreqUnitsDropDown.Value = 'GHz';
            app.PositionControllerPanel = uipanel(app.SetParamsPanel);
            app.PositionControllerPanel.Title = 'Position Controller';
            app.AzimuthParametersPanel = uipanel(app.PositionControllerPanel);
            app.AzimuthParametersPanel.Title = 'Azimuth Parameters';
            app.GridLayout = uigridlayout(app.AzimuthParametersPanel);
            app.AZLimitsLabel = uilabel(app.GridLayout);
            app.AZLimitsLabel.Text = '(1-180)';
            app.AZIncrementSizeEditFieldLabel = uilabel(app.GridLayout);
            app.AZIncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.AZIncrementSizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.AZIncrementSizeEditField.Editable = 'off';
            app.AZStartPositionEditFieldLabel = uilabel(app.GridLayout);
            app.AZStartPositionEditFieldLabel.Text = 'Start Position';
            app.AZStartPositionEditField = uieditfield(app.GridLayout, 'numeric');
            app.AZStartPositionEditField.Editable = 'off';
            app.AZStartPositionEditField.Value = -90;
            app.AZEndPositionEditFieldLabel = uilabel(app.GridLayout);
            app.AZEndPositionEditFieldLabel.Text = 'End Position';
            app.AZEndPositionEditField = uieditfield(app.GridLayout, 'numeric');
            app.AZEndPositionEditField.Editable = 'off';
            app.AZEndPositionEditField.Value = 90;
            app.RollParametersPanel = uipanel(app.PositionControllerPanel);
            app.RollParametersPanel.Title = 'Roll Parameters';
            app.GridLayout2 = uigridlayout(app.RollParametersPanel);
            app.RollLimitsLabel = uilabel(app.GridLayout2);
            app.RollLimitsLabel.Text = '(1-360)';
            app.RollIncrementSizeEditFieldLabel = uilabel(app.GridLayout2);
            app.RollIncrementSizeEditFieldLabel.Text = 'Increment Size';
            app.RollIncrementSizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollIncrementSizeEditField.Editable = 'off';
            app.RollStartPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.RollStartPositionEditFieldLabel.Text = 'Start Position';
            app.RollStartPositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollStartPositionEditField.Editable = 'off';
            app.RollEndPositionEditFieldLabel = uilabel(app.GridLayout2);
            app.RollEndPositionEditFieldLabel.Text = 'End Position';
            app.RollEndPositionEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollEndPositionEditField.Editable = 'off';
            app.RollEndPositionEditField.Value = 360;
            app.FixedPositionCheckBox = uicheckbox(app.PositionControllerPanel);
            app.FixedPositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixedPositionCheckBoxValueChanged, true);
            app.FixedPositionCheckBox.Text = 'Fixed Position';
            app.TabGroup = uitabgroup(app.TakeMeasurement);
            app.OutputConsoleTab = uitab(app.TabGroup);
            app.OutputConsoleTab.Title = 'Output Console';
            app.OutputConsoleTextArea = uitextarea(app.OutputConsoleTab);
            app.OutputConsoleTextArea.Editable = 'off';
            app.ErrorLogTab = uitab(app.TabGroup);
            app.ErrorLogTab.Title = 'Error Log';
            app.ErrorLogTextArea = uitextarea(app.ErrorLogTab);
            app.ErrorLogTextArea.Editable = 'off';
            app.ManagementPanel = uipanel(app.TakeMeasurement);
            app.ManagementPanel.Title = 'Management';
            app.EndMeasurementButton = uibutton(app.ManagementPanel, 'push');
            app.EndMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @EndMeasurementButtonPushed, true);
            app.EndMeasurementButton.Text = 'End Measurement';
            app.ExportDataButton = uibutton(app.ManagementPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.ProgressBar = uipanel(app.TakeMeasurement);
            app.ProgressBar.Tooltip = {'Progress Bar to indicate current progress during a measurement cycle.'};
            % Show the figure after all components are created
            app.TakeMeasurement.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TakeMeasurementApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TakeMeasurement)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TakeMeasurement)
        end
    end
end