classdef measApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MeasurementApp                  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        MeasurementMenu                 matlab.ui.container.Menu
        SingleFrequencyMeasurementMenu  matlab.ui.container.Menu
        SpanFrequencyMeasurementMenu    matlab.ui.container.Menu
        ProjectMenu                     matlab.ui.container.Menu
        Menu2_3                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        OpenFileMenu                    matlab.ui.container.Menu
        OpenProjectMenu                 matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveAsMenu                      matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        EnterFullscreenModeMenu         matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        CoreFunctionsPanel              matlab.ui.container.Panel
        CoreFunctionsGridLayout         matlab.ui.container.GridLayout
        ProjectManagementButton         matlab.ui.control.Button
        NewMeasurementSetupButton       matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        DataAcquisitionPanel            matlab.ui.container.Panel
        DataAcquisitionGridLayout       matlab.ui.container.GridLayout
        ConfigureInstrumentsButton      matlab.ui.control.Button
        PlotDataButton                  matlab.ui.control.Button
        WorkWithInstrumentsButton       matlab.ui.control.Button
        AntennaAnalysisPanel            matlab.ui.container.Panel
        AntennaAnalysisGridLayout       matlab.ui.container.GridLayout
        DataFileManipulationButton      matlab.ui.control.Button
        MeasuredDataCorrectionButton    matlab.ui.control.Button
        ViewPreviousResultsButton       matlab.ui.control.Button
        ViewDataFileButton              matlab.ui.control.Button
        ImportDataButton                matlab.ui.control.Button
        MiscellaneousPanel              matlab.ui.container.Panel
        MiscellaneousGridLayout         matlab.ui.container.GridLayout
        ManualButton                    matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        measAppWindow; % Description
        ProjectManagerAppObj;
        NewProjectAppObj;
        NewMeasurementAppObj;
        TakeMeasurementAppObj;
        PlotDataAppObj;
        ExportDataOptionsAppObj;
        ExportDataAppObj;
        
        MeasurementAppNameObj = 'Data Acquisition & Analysis';
        
        ProjectDirectoryObj;
        ProjectFileObj;
        ProjectFileFullPathObj;
        ProjectDirectoryNameObj;
    
        DataToWriteObj;
    end
    
    methods (Access = private)


    end
    
    methods (Access = public)
        
        function setAppOnTop(app,shouldAppBeOnTop,windowTitle)
            activeWindows = matlab.internal.webwindowmanager.instance.windowList;
            measAppWindowObj = contains({activeWindows.Title},windowTitle);
            if any(measAppWindowObj)
                app.measAppWindow = activeWindows(measAppWindowObj);
                app.measAppWindow.setAlwaysOnTop(shouldAppBeOnTop);
            end
            event.Source.Visible = false;            
        end
        
        function setWindowTitle(app,newTitle)
           app.MeasurementApp.Name = [newTitle, ' || ',app.MeasurementAppNameObj];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pause(1);
            setAppOnTop(app,true,app.MeasurementAppNameObj);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            setAppOnTop(app,false,app.MeasurementApp.Name);
            try 
                [file,path] = uiputfile('','Save File');
            catch
                error('[%s] You must specify a valid file to save.\n',datestr(now,'HH:MM:SS'));
            end
            
            if isequal(file,0) || isequal(path,0)
               fprintf(2,'[%s] You must specify a valid file to save.\n',datestr(now,'HH:MM:SS'));
            else
               app.ProjectDirectoryObj = dir(path);
               indexOfFileInDirectory = strcmp({app.ProjectDirectoryObj.name}, file) == 1;
               app.ProjectFileObj = app.ProjectDirectoryObj(indexOfFileInDirectory);
               
               disp(['User selected ',fullfile(path,file),' and then clicked Save.'])
               
               if (isunix || ismac) app.ProjectFileFullPathObj = [app.ProjectFileObj.folder '/' app.ProjectFileObj.name(1:end - 4)]; end
               if ispc app.ProjectFileFullPathObj = [app.ProjectFileObj.folder '\' app.ProjectFileObj.name(1:end - 4)]; end
              % app.DataToWriteObj = load([app.ProjectFileFullPathObj '.mat']).dataToWrite;
               setAppOnTop(app,true,app.MeasurementApp.Name);     
            end
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            setAppOnTop(app,false,app.MeasurementApp.Name);
            try 
                selectedFolder = uigetdir;
            catch 
                error('[%s] You must specify a project folder to open.\n',datestr(now,'HH:MM:SS'));
            end
            if isequal(selectedFolder,0)
               fprintf(2,'[%s] You must specify a project folder to open.\n',datestr(now,'HH:MM:SS'));
            else
               setWindowTitle(app,selectedFolder);
               app.ProjectDirectoryObj = dir(selectedFolder);
               app.ProjectDirectoryNameObj = app.ProjectDirectoryObj(1).folder;
               disp(['User selected ', selectedFolder]);
            end
            setAppOnTop(app,true,app.MeasurementApp.Name);
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuSelected(app, event)
            setAppOnTop(app,false,app.MeasurementApp.Name);
            try 
                [file,path] = uigetfile;
            catch
                error('[%s] You must choose a valid file to open.\n',datestr(now,'HH:MM:SS'));
            end
            if isequal(file,0)
               fprintf(2,'[%s] You must choose a valid file to open.\n',datestr(now,'HH:MM:SS'));
            else
                setWindowTitle(app,[path file]);
                
                app.ProjectDirectoryObj = dir(path);
                indexOfFileInDirectory = strcmp({app.ProjectDirectoryObj.name}, file) == 1;
                app.ProjectFileObj = app.ProjectDirectoryObj(indexOfFileInDirectory);
                
                disp(['User selected ', fullfile(path,file)]);
                
                if (isunix || ismac) app.ProjectFileFullPathObj = [app.ProjectFileObj.folder '/' app.ProjectFileObj.name(1:end - 4)]; end
                if ispc app.ProjectFileFullPathObj = [app.ProjectFileObj.folder '\' app.ProjectFileObj.name(1:end - 4)]; end
                app.DataToWriteObj = load([app.ProjectFileFullPathObj '.mat']).dataToWrite;
            end
            setAppOnTop(app,true,app.MeasurementApp.Name);
        end

        % Close request function: MeasurementApp
        function MeasurementAppCloseRequest(app, event)
            delete(app.NewProjectAppObj);
            delete(app.NewMeasurementAppObj);
            delete(app.TakeMeasurementAppObj);
            delete(app.PlotDataAppObj);
            delete(app.ExportDataOptionsAppObj);
            delete(app.ExportDataAppObj);
            delete(app.ProjectManagerAppObj);
           
            delete(app);
        end

        % Menu selected function: EnterFullscreenModeMenu
        function EnterFullscreenModeMenuSelected(app, event)
            if (app.measAppWindow.isFullscreen)
                app.ViewMenu.Children(2).Text = 'Enter Fullscreen Mode';
                app.measAppWindow.restore;
            else
                app.ViewMenu.Children(2).Text = 'Exit Fullscreen Mode';
                app.measAppWindow.fullscreen;
            end
        end

        % Button pushed function: ProjectManagementButton
        function ProjectManagementButtonPushed(app, event)
            app.ProjectManagementButton.Enable = 'off';
            
            app.ProjectManagerAppObj = ProjectManagerApp(app);
        end

        % Button pushed function: NewMeasurementSetupButton
        function NewMeasurementSetupButtonPushed(app, event)
            app.NewMeasurementSetupButton.Enable = 'off';
            
            app.NewMeasurementAppObj = NewMeasurementApp(app);
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            app.PlotDataButton.Enable = 'off';
            
            if (isempty(app.ProjectFileObj))
                try 
                    OpenFileMenuSelected(app);  
                catch
                    app.PlotDataButton.Enable = 'on';
                    setAppOnTop(app,true,app.MeasurementApp.Name);
                    fprintf(2,'[%s] You must choose a valid file to open.\n',datestr(now,'HH:MM:SS'));
                end
            end
            
            if (~isempty(app.ProjectFileObj)) 
                app.PlotDataAppObj = PlotDataApp(app, app);
            else
               app.PlotDataButton.Enable = 'on';
               setAppOnTop(app,true,app.MeasurementApp.Name); 
            end
            

        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            app.ExportDataButton.Enable = 'off';
            
            app.ExportDataOptionsAppObj = ExportDataOptionsApp(app,app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MeasurementApp = uifigure('Visible', 'off');
            app.MeasurementApp.Name = 'Data Acquisition & Analysis';
            app.MeasurementApp.CloseRequestFcn = createCallbackFcn(app, @MeasurementAppCloseRequest, true);
            app.FileMenu = uimenu(app.MeasurementApp);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.MeasurementMenu = uimenu(app.NewMenu);
            app.MeasurementMenu.Text = 'Measurement';
            app.SingleFrequencyMeasurementMenu = uimenu(app.MeasurementMenu);
            app.SingleFrequencyMeasurementMenu.Text = 'Single Frequency Measurement';
            app.SpanFrequencyMeasurementMenu = uimenu(app.MeasurementMenu);
            app.SpanFrequencyMeasurementMenu.Text = 'Span Frequency Measurement';
            app.ProjectMenu = uimenu(app.NewMenu);
            app.ProjectMenu.Text = 'Project';
            app.Menu2_3 = uimenu(app.NewMenu);
            app.Menu2_3.Text = 'Menu2';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.OpenFileMenu = uimenu(app.OpenMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFileMenu.Text = 'File';
            app.OpenProjectMenu = uimenu(app.OpenMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Text = 'Project';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.EditMenu = uimenu(app.MeasurementApp);
            app.EditMenu.Text = 'Edit';
            app.Menu = uimenu(app.EditMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.EditMenu);
            app.Menu2.Text = 'Menu2';
            app.ViewMenu = uimenu(app.MeasurementApp);
            app.ViewMenu.Text = 'View';
            app.EnterFullscreenModeMenu = uimenu(app.ViewMenu);
            app.EnterFullscreenModeMenu.MenuSelectedFcn = createCallbackFcn(app, @EnterFullscreenModeMenuSelected, true);
            app.EnterFullscreenModeMenu.Text = 'Enter Fullscreen Mode';
            app.Menu2_2 = uimenu(app.ViewMenu);
            app.Menu2_2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.MeasurementApp);
            app.CoreFunctionsPanel = uipanel(app.GridLayout);
            app.CoreFunctionsPanel.Title = 'Core Functions';
            app.CoreFunctionsGridLayout = uigridlayout(app.CoreFunctionsPanel);
            app.ProjectManagementButton = uibutton(app.CoreFunctionsGridLayout, 'push');
            app.ProjectManagementButton.ButtonPushedFcn = createCallbackFcn(app, @ProjectManagementButtonPushed, true);
            app.ProjectManagementButton.Text = 'Project Management';
            app.NewMeasurementSetupButton = uibutton(app.CoreFunctionsGridLayout, 'push');
            app.NewMeasurementSetupButton.ButtonPushedFcn = createCallbackFcn(app, @NewMeasurementSetupButtonPushed, true);
            app.NewMeasurementSetupButton.Text = {'New Measurement / '; 'Setup'};
            app.ExportDataButton = uibutton(app.CoreFunctionsGridLayout, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.DataAcquisitionPanel = uipanel(app.GridLayout);
            app.DataAcquisitionPanel.Title = 'Data Acquisition';
            app.DataAcquisitionGridLayout = uigridlayout(app.DataAcquisitionPanel);
            app.ConfigureInstrumentsButton = uibutton(app.DataAcquisitionGridLayout, 'push');
            app.ConfigureInstrumentsButton.Text = 'Configure Instruments';
            app.PlotDataButton = uibutton(app.DataAcquisitionGridLayout, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.WorkWithInstrumentsButton = uibutton(app.DataAcquisitionGridLayout, 'push');
            app.WorkWithInstrumentsButton.Text = 'Work With Instruments';
            app.AntennaAnalysisPanel = uipanel(app.GridLayout);
            app.AntennaAnalysisPanel.Title = 'Antenna Analysis';
            app.AntennaAnalysisGridLayout = uigridlayout(app.AntennaAnalysisPanel);
            app.DataFileManipulationButton = uibutton(app.AntennaAnalysisGridLayout, 'push');
            app.DataFileManipulationButton.Text = 'Data File Manipulation';
            app.MeasuredDataCorrectionButton = uibutton(app.AntennaAnalysisGridLayout, 'push');
            app.MeasuredDataCorrectionButton.Text = 'Measured Data Correction';
            app.ViewPreviousResultsButton = uibutton(app.AntennaAnalysisGridLayout, 'push');
            app.ViewPreviousResultsButton.Text = 'View Previous Results';
            app.ViewDataFileButton = uibutton(app.AntennaAnalysisGridLayout, 'push');
            app.ViewDataFileButton.Text = 'View Data File';
            app.ImportDataButton = uibutton(app.AntennaAnalysisGridLayout, 'push');
            app.ImportDataButton.Text = 'Import Data';
            app.MiscellaneousPanel = uipanel(app.GridLayout);
            app.MiscellaneousPanel.Title = 'Miscellaneous';
            app.MiscellaneousGridLayout = uigridlayout(app.MiscellaneousPanel);
            app.ManualButton = uibutton(app.MiscellaneousGridLayout, 'push');
            app.ManualButton.Text = 'Manual';
            app.ExitButton = uibutton(app.MiscellaneousGridLayout, 'push');
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.MeasurementApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = measApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MeasurementApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MeasurementApp)
        end
    end
end